classdef MAGMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAGMAUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        HomeTabGL                       matlab.ui.container.GridLayout
        ModelBioChemT                   matlab.ui.control.Table
        ModelSavedL                     matlab.ui.control.Label
        ModelSaveB                      matlab.ui.control.Button
        ModelSearchByDD                 matlab.ui.control.DropDown
        SearchByLabel                   matlab.ui.control.Label
        ModelDBLoadB                    matlab.ui.control.Button
        ModelSearchB                    matlab.ui.control.Button
        ModelSearchVF                   matlab.ui.control.EditField
        ModelSearchTree                 matlab.ui.container.Tree
        ModelFileLoadB                  matlab.ui.control.Button
        CreateNewModelB                 matlab.ui.control.Button
        ModelSave2DBB                   matlab.ui.control.Button
        ModelSave2FileB                 matlab.ui.control.Button
        ModelNameTA                     matlab.ui.control.TextArea
        ModelNameLabel                  matlab.ui.control.Label
        ModelDescHTA                    matlab.ui.control.TextArea
        ModelDescriptionLabel           matlab.ui.control.Label
        RefHTA                          matlab.ui.control.TextArea
        ReferencesLabel                 matlab.ui.control.Label
        EnvironmentTab                  matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        EnvironmentDesignPanel          matlab.ui.container.Panel
        GridLayout36                    matlab.ui.container.GridLayout
        Button_3                        matlab.ui.control.Button
        IncidentLightFuncL              matlab.ui.control.Label
        IncidentLightFuncVF             matlab.ui.control.EditField
        EditField_9                     matlab.ui.control.NumericEditField
        DropDown_7                      matlab.ui.control.DropDown
        EnvGeomUB                       matlab.ui.control.Button
        AgSpdL                          matlab.ui.control.Label
        HelpBut9                        matlab.ui.control.Button
        HelpBut8                        matlab.ui.control.Button
        HelpBut7                        matlab.ui.control.Button
        HelpBut6                        matlab.ui.control.Button
        HelpBut5                        matlab.ui.control.Button
        HelpBut4                        matlab.ui.control.Button
        HelpBut3                        matlab.ui.control.Button
        HelpBut2                        matlab.ui.control.Button
        ReactParamUnit8DD               matlab.ui.control.DropDown
        ReactParam8VF                   matlab.ui.control.NumericEditField
        LightSourceCircleRadiusLabel    matlab.ui.control.Label
        LightSourceDiameterLabel        matlab.ui.control.Label
        LightSourcesLabel               matlab.ui.control.Label
        TankHeightLabel                 matlab.ui.control.Label
        TankInnerDiameterLabel          matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        HelpBut1                        matlab.ui.control.Button
        ReactParamUnit6DD               matlab.ui.control.DropDown
        ReactParamUnit4DD               matlab.ui.control.DropDown
        ReactParam4VF                   matlab.ui.control.NumericEditField
        ReactParam3VF                   matlab.ui.control.NumericEditField
        ReactParamUnit3DD               matlab.ui.control.DropDown
        ReactParamUnit2DD               matlab.ui.control.DropDown
        ReactParam2VF                   matlab.ui.control.NumericEditField
        ReactParamUnit7DD               matlab.ui.control.DropDown
        ReactParam7VF                   matlab.ui.control.NumericEditField
        ReactParam6VF                   matlab.ui.control.NumericEditField
        ReactParam5DD                   matlab.ui.control.DropDown
        LightingConfigurationDropDownLabel  matlab.ui.control.Label
        ReactParamUnit1DD               matlab.ui.control.DropDown
        ReactParam1VF                   matlab.ui.control.NumericEditField
        ReactorTypeDD                   matlab.ui.control.DropDown
        ReactorTypeDropDownLabel        matlab.ui.control.Label
        ReactorImg                      matlab.ui.control.Image
        EnvironParamFuncL               matlab.ui.control.Label
        GenParamDD                      matlab.ui.control.DropDown
        genparamddLabel                 matlab.ui.control.Label
        GenParamAB                      matlab.ui.control.Button
        GenParamUnitDD                  matlab.ui.control.DropDown
        GenParamF                       matlab.ui.control.EditField
        EnvironT                        matlab.ui.control.Table
        EnvironParamFuncPlot            matlab.ui.control.UIAxes
        SpecTab                         matlab.ui.container.Tab
        SpecEnvironGrid                 matlab.ui.container.GridLayout
        Button_9                        matlab.ui.control.Button
        MWVF                            matlab.ui.control.NumericEditField
        MolecularWeightEditFieldLabel   matlab.ui.control.Label
        ChemTypeDD                      matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        SorpEqML                        matlab.ui.control.Label
        CompSolutesL                    matlab.ui.control.Label
        HConstL                         matlab.ui.control.Label
        SpecInitConcF                   matlab.ui.control.NumericEditField
        ChemInitConcF                   matlab.ui.control.NumericEditField
        Button_8                        matlab.ui.control.Button
        Button_7                        matlab.ui.control.Button
        Button_6                        matlab.ui.control.Button
        Button_5                        matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        Param3L                         matlab.ui.control.Label
        Param2L                         matlab.ui.control.Label
        Param1L                         matlab.ui.control.Label
        HConst2HB                       matlab.ui.control.Button
        HConstHB                        matlab.ui.control.Button
        HConst2L                        matlab.ui.control.Label
        HConst2VF                       matlab.ui.control.NumericEditField
        HConst2UDD                      matlab.ui.control.DropDown
        SorpParam3VF                    matlab.ui.control.NumericEditField
        SorpParam2VF                    matlab.ui.control.NumericEditField
        SorpParam1VF                    matlab.ui.control.NumericEditField
        SorptionModelDD                 matlab.ui.control.DropDown
        SorptionModelRB                 matlab.ui.control.Button
        SorptionModelUB                 matlab.ui.control.Button
        SorptionT                       matlab.ui.control.Table
        CompSoluteDD                    matlab.ui.control.DropDown
        HConstUDD                       matlab.ui.control.DropDown
        HConstVF                        matlab.ui.control.NumericEditField
        VolCB                           matlab.ui.control.CheckBox
        ChemRB                          matlab.ui.control.Button
        ChemT                           matlab.ui.control.Table
        ChemInitConcUnitDD              matlab.ui.control.DropDown
        ChemInitConcL                   matlab.ui.control.Label
        ChemNameDD                      matlab.ui.control.DropDown
        ChemicalNameLabel               matlab.ui.control.Label
        ChemAB                          matlab.ui.control.Button
        SpecInitConcUnitDD              matlab.ui.control.DropDown
        InitialConcentrationEditField_2Label  matlab.ui.control.Label
        SpecRB                          matlab.ui.control.Button
        SpecT                           matlab.ui.control.Table
        SpecAB                          matlab.ui.control.Button
        SpecNameDD                      matlab.ui.control.DropDown
        SpeciesNameLabel                matlab.ui.control.Label
        MassTransferTab                 matlab.ui.container.Tab
        GridLayout27                    matlab.ui.container.GridLayout
        MTP                             matlab.ui.container.Panel
        GridLayout37                    matlab.ui.container.GridLayout
        MTModelUB                       matlab.ui.control.Button
        Button_11                       matlab.ui.control.Button
        MTUpLimOpDD                     matlab.ui.control.DropDown
        MTLowLimOpDD                    matlab.ui.control.DropDown
        MTHelperUB                      matlab.ui.control.Button
        MTHelperRB                      matlab.ui.control.Button
        MTHelperDD                      matlab.ui.control.DropDown
        MTHelperSaveL                   matlab.ui.control.Label
        MTHelperFuncL                   matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        MTHelperValF                    matlab.ui.control.EditField
        MTHelperSymF                    matlab.ui.control.EditField
        MTHelperNameF                   matlab.ui.control.EditField
        MTHelperAB                      matlab.ui.control.Button
        IfLabel                         matlab.ui.control.Label
        MTLowLimVF                      matlab.ui.control.EditField
        MTPWVarDD                       matlab.ui.control.DropDown
        MTUpLimVF                       matlab.ui.control.EditField
        MTElseValDD                     matlab.ui.control.DropDown
        ElseDropDown_2Label             matlab.ui.control.Label
        MTPiecewiseT                    matlab.ui.control.Table
        MTPWRB                          matlab.ui.control.Button
        MTPWAB                          matlab.ui.control.Button
        MTPWDD                          matlab.ui.control.DropDown
        ExpressionDropDown_2Label       matlab.ui.control.Label
        MTFuncNewAB                     matlab.ui.control.Button
        MTFuncTypeDD                    matlab.ui.control.DropDown
        MTExpRefB                       matlab.ui.control.Button
        MTGovFuncVF                     matlab.ui.control.EditField
        MTPhaseBDD                      matlab.ui.control.DropDown
        MTPhaseBL                       matlab.ui.control.Label
        MTPhaseADD                      matlab.ui.control.DropDown
        GoverningEquationofLabel        matlab.ui.control.Label
        MTCompDD                        matlab.ui.control.DropDown
        ComponentDropDownLabel_2        matlab.ui.control.Label
        MTSysVarT                       matlab.ui.control.Table
        MTSaveL                         matlab.ui.control.Label
        MTGovFuncL                      matlab.ui.control.Label
        InputandOutputStreamsPanel      matlab.ui.container.Panel
        GridLayout34                    matlab.ui.container.GridLayout
        Button_10                       matlab.ui.control.Button
        BubPhysSpecB                    matlab.ui.control.Button
        GasOutFlowCB                    matlab.ui.control.CheckBox
        GasInFlowCB                     matlab.ui.control.CheckBox
        LiqOutFlowCB                    matlab.ui.control.CheckBox
        LiqInFlowCB                     matlab.ui.control.CheckBox
        NewStrmNF                       matlab.ui.control.EditField
        NewStreamNameEditFieldLabel     matlab.ui.control.Label
        StrmAB                          matlab.ui.control.Button
        StrmTypeL                       matlab.ui.control.Label
        StrmRB                          matlab.ui.control.Button
        StrmNDD                         matlab.ui.control.DropDown
        StreamNameDropDownLabel         matlab.ui.control.Label
        StrmTypeDD                      matlab.ui.control.DropDown
        StrmT                           matlab.ui.control.Table
        GoverningFunctionsTab           matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        HelperFuncP                     matlab.ui.container.Panel
        GridLayout21                    matlab.ui.container.GridLayout
        HelperSaveL                     matlab.ui.control.Label
        HelperFuncL                     matlab.ui.control.Label
        HelperAB                        matlab.ui.control.Button
        Label_16                        matlab.ui.control.Label
        HelperNameF                     matlab.ui.control.EditField
        HelperSymF                      matlab.ui.control.EditField
        HelperValF                      matlab.ui.control.EditField
        Label_15                        matlab.ui.control.Label
        HelperRB                        matlab.ui.control.Button
        HelperUB                        matlab.ui.control.Button
        HelperDD                        matlab.ui.control.DropDown
        PiecewiseLimsP                  matlab.ui.container.Panel
        GridLayout20                    matlab.ui.container.GridLayout
        UpLimOpDD                       matlab.ui.control.DropDown
        LowLimOpDD                      matlab.ui.control.DropDown
        UpLimVF                         matlab.ui.control.NumericEditField
        LowLimVF                        matlab.ui.control.NumericEditField
        ElseValDD                       matlab.ui.control.DropDown
        ElseDropDownLabel               matlab.ui.control.Label
        PiecewiseDD                     matlab.ui.control.DropDown
        ExpressionDropDownLabel         matlab.ui.control.Label
        PiecewiseRB                     matlab.ui.control.Button
        PiecewiseAB                     matlab.ui.control.Button
        GovFuncPiecewiseT               matlab.ui.control.Table
        PiecewiseVarDD                  matlab.ui.control.DropDown
        IfLabel_2                       matlab.ui.control.Label
        SubFuncPanel                    matlab.ui.container.Panel
        GridLayout19                    matlab.ui.container.GridLayout
        OpenExpressionsReferenceButton  matlab.ui.control.Button
        GovFuncVF                       matlab.ui.control.EditField
        ModelUB                         matlab.ui.control.Button
        FuncNewAB                       matlab.ui.control.Button
        FuncTypeDD                      matlab.ui.control.DropDown
        SysVarT                         matlab.ui.control.Table
        GovFuncPanel                    matlab.ui.container.Panel
        GridLayout18                    matlab.ui.container.GridLayout
        SaveL                           matlab.ui.control.Label
        GovFuncL                        matlab.ui.control.Label
        InteractCompDD                  matlab.ui.control.DropDown
        ComponentDropDownLabel_3        matlab.ui.control.Label
        ModelReviewTab                  matlab.ui.container.Tab
        GridLayout39                    matlab.ui.container.GridLayout
        ModelFunctionsPanel             matlab.ui.container.Panel
        SummaryL                        matlab.ui.control.Label
        ModelSolverSettingsButton       matlab.ui.control.Button
        ParametersTab                   matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        ParamSymDD                      matlab.ui.control.DropDown
        ParameterSymbolDropDownLabel    matlab.ui.control.Label
        ParamNF                         matlab.ui.control.EditField
        ParameterNameEditFieldLabel     matlab.ui.control.Label
        CompParamDD                     matlab.ui.control.DropDown
        SpeciesChemicalLabel            matlab.ui.control.Label
        ParamUB                         matlab.ui.control.Button
        ParamUnitDD                     matlab.ui.control.DropDown
        ParamValF                       matlab.ui.control.NumericEditField
        ParamNumDD                      matlab.ui.control.DropDown
        ParameterNumberLabel            matlab.ui.control.Label
        ParamT                          matlab.ui.control.Table
        RegressionTab                   matlab.ui.container.Tab
        GridLayout32                    matlab.ui.container.GridLayout
        RegAnalysisB                    matlab.ui.control.Button
        RegSpecsB                       matlab.ui.control.Button
        VarDataUnmatchB                 matlab.ui.control.Button
        RegCompParamDD                  matlab.ui.control.DropDown
        ComponentDropDown_2Label        matlab.ui.control.Label
        RegVarDataT                     matlab.ui.control.Table
        RegDVAB                         matlab.ui.control.Button
        RegDVDD                         matlab.ui.control.DropDown
        DependentVariableLabel          matlab.ui.control.Label
        ImportDataB                     matlab.ui.control.Button
        SaveAllRegParamB                matlab.ui.control.Button
        SaveRegParamDD                  matlab.ui.control.DropDown
        ParameterDropDownLabel_2        matlab.ui.control.Label
        RunRegB                         matlab.ui.control.Button
        RegParamT                       matlab.ui.control.Table
        VarDataMatchB                   matlab.ui.control.Button
        ImportedDataDD                  matlab.ui.control.DropDown
        ImportedDataDropDownLabel       matlab.ui.control.Label
        RegSysVarDD                     matlab.ui.control.DropDown
        SystemVariableDropDownLabel     matlab.ui.control.Label
        RegParamRB                      matlab.ui.control.Button
        RegParamAB                      matlab.ui.control.Button
        ParametersDropDownLabel         matlab.ui.control.Label
        SaveRegParamB                   matlab.ui.control.Button
        RegParamDD                      matlab.ui.control.DropDown
        RegPlot                         matlab.ui.control.UIAxes
        PlotCustomizationTab            matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ModelResultsExportPanel         matlab.ui.container.Panel
        GridLayout38                    matlab.ui.container.GridLayout
        DataExportDIrL                  matlab.ui.control.Label
        DataExportDirB                  matlab.ui.control.Button
        DataExportB                     matlab.ui.control.Button
        SubplotRB                       matlab.ui.control.Button
        SubplotDD                       matlab.ui.control.DropDown
        SubplotDropDownLabel            matlab.ui.control.Label
        SubplotAB                       matlab.ui.control.Button
        PlotPosDD                       matlab.ui.control.DropDown
        PlotPositionDropDownLabel       matlab.ui.control.Label
        SubplotCVF                      matlab.ui.control.NumericEditField
        ofSubplotColumnsEditFieldLabel  matlab.ui.control.Label
        SubplotGroupDD                  matlab.ui.control.DropDown
        PlotGroupDropDownLabel          matlab.ui.control.Label
        SubplotUB                       matlab.ui.control.Button
        SubplotRVF                      matlab.ui.control.NumericEditField
        ofSubplotRowsEditFieldLabel     matlab.ui.control.Label
        Label4                          matlab.ui.control.Label
        PlotTitleF                      matlab.ui.control.EditField
        TitleEditFieldLabel_2           matlab.ui.control.Label
        PlotTitleDD                     matlab.ui.control.DropDown
        PlotAB                          matlab.ui.control.Button
        PlotDownloadP                   matlab.ui.container.Panel
        GridLayout25                    matlab.ui.container.GridLayout
        FileTypeDD                      matlab.ui.control.DropDown
        FileTypeDropDownLabel           matlab.ui.control.Label
        PlotDownloadDirB                matlab.ui.control.Button
        PlotDirL                        matlab.ui.control.Label
        PlotGenerationP                 matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        PlotDispTree                    matlab.ui.container.CheckBoxTree
        SelectPlotsL                    matlab.ui.control.Label
        ClosePrevPlotsCB                matlab.ui.control.CheckBox
        OptionsL                        matlab.ui.control.Label
        GenPlotB                        matlab.ui.control.Button
        PlotInitCondP                   matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        AddSpecVF3                      matlab.ui.control.NumericEditField
        AddSpecVF2                      matlab.ui.control.NumericEditField
        AddSpecVF1                      matlab.ui.control.NumericEditField
        evalUnitsVF                     matlab.ui.control.EditField
        ICEvalVarDD                     matlab.ui.control.DropDown
        ICEvalAxisDD                    matlab.ui.control.DropDown
        AddSpecL3                       matlab.ui.control.Label
        AddSpecL2                       matlab.ui.control.Label
        AddSpecL1                       matlab.ui.control.Label
        UnitsLabel                      matlab.ui.control.Label
        VariableDropDownLabel_2         matlab.ui.control.Label
        AxisDropDownLabel               matlab.ui.control.Label
        PlotICEvalSpecUB                matlab.ui.control.Button
        SysVarInitCondT                 matlab.ui.control.Table
        AxisSpecificationsP             matlab.ui.container.Panel
        GridLayout22                    matlab.ui.container.GridLayout
        Button_14                       matlab.ui.control.Button
        Button_13                       matlab.ui.control.Button
        Button_12                       matlab.ui.control.Button
        Label3                          matlab.ui.control.Label
        Label2                          matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        ZAxUpLimVF                      matlab.ui.control.NumericEditField
        ZAxLoLimVF                      matlab.ui.control.NumericEditField
        XAxUpLimVF                      matlab.ui.control.NumericEditField
        XAxLoLimVF                      matlab.ui.control.NumericEditField
        YAxUpLimVF                      matlab.ui.control.NumericEditField
        YAxLoLimVF                      matlab.ui.control.NumericEditField
        ZAxVarDD                        matlab.ui.control.DropDown
        YAxVarDD                        matlab.ui.control.DropDown
        XAxVarDD                        matlab.ui.control.DropDown
        ZAxVarRB                        matlab.ui.control.Button
        ZAxVarAB                        matlab.ui.control.Button
        XAxVarRB                        matlab.ui.control.Button
        XAxVarAB                        matlab.ui.control.Button
        XAxVarL                         matlab.ui.control.Label
        ZAxVarL                         matlab.ui.control.Label
        YAxVarRB                        matlab.ui.control.Button
        YAxVarAB                        matlab.ui.control.Button
        YAxVarL                         matlab.ui.control.Label
        ZAxDispLimUB                    matlab.ui.control.Button
        YAxDispLimUB                    matlab.ui.control.Button
        XAxDispLimUB                    matlab.ui.control.Button
        Div1                            matlab.ui.control.Label
        Div2                            matlab.ui.control.Label
        Div3                            matlab.ui.control.Label
        UpperDisplayLimitDropDown_4Label  matlab.ui.control.Label
        LowerDisplayLimitDropDown_4Label  matlab.ui.control.Label
        UpperDisplayLimitDropDown_2Label  matlab.ui.control.Label
        LowerDisplayLimitDropDown_2Label  matlab.ui.control.Label
        UpperDisplayLimitDropDown_3Label  matlab.ui.control.Label
        LowerDisplayLimitDropDown_3Label  matlab.ui.control.Label
        ZAxDefaultRCB                   matlab.ui.control.CheckBox
        VariableDropDown_3Label         matlab.ui.control.Label
        YAxDefaultRCB                   matlab.ui.control.CheckBox
        VariableDropDown_2Label         matlab.ui.control.Label
        VariableDropDownLabel           matlab.ui.control.Label
        NbAxesDD                        matlab.ui.control.DropDown
        NumberofAxesDropDownLabel       matlab.ui.control.Label
        XAxDefaultRCB                   matlab.ui.control.CheckBox
        ZAxTitleF                       matlab.ui.control.EditField
        ZAxisTitleEditFieldLabel        matlab.ui.control.Label
        YAxTitleF                       matlab.ui.control.EditField
        YAxisTitleLabel                 matlab.ui.control.Label
        XAxTitleF                       matlab.ui.control.EditField
        XAxisTitleLabel                 matlab.ui.control.Label
        PlotRB                          matlab.ui.control.Button
        PlotsDropDownLabel              matlab.ui.control.Label
        ContextMenu2                    matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2_2                         matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Property % Description
        DataImportWindow
        RegSpecsWindow
        RegAnalysisWindow

        blk_name = "";
        system = []; % Placeholder for ODESys

        plotDispTreeNodes = {};
        % structure
        % tree:
        %   name: plot_title
        %   main: uitree_node
        %       display: uitree_node
        %       download: uitree_node

        envParamNames = {'Model Run Time (t)';'Incident Light (I_0(t))';'Temperature (T(t))';'Pressure (P(t))'; ...
            'Initial Culture Volume (V_0)';'Initial pH (pH_0)';'Agitation Speed';'Tank Height';'Tank Diameter';'Maximum Volume (V_m)'; ...
            'Tank Surface Area';'Light Configuration';'# Light Sources';'Light Source Diameter';'Light Source Circle Radius'};
    end
    
    methods (Access = private)
        function updateSumL(app)
            % updates MSumL text
            % update the text display for the function value in MSumL
            app.SummaryL.Text = "$";

            comps = app.system.getAllComps();
            helperNames = app.system.getEnvAndHelperFuncNames();
            for k=1:1:length(comps)+length(helperNames)
                if k <= length(comps)-5
                    names = comps{k}.getAllPhaseNames();
                    for l=1:1:length(names)
                        funcSym = app.system.getCompSymByName(names(l));
                        govFunc = app.system.getGovFuncByCompName(names(l));
                        if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                        app.SummaryL.Text = app.SummaryL.Text + "\frac{d"+funcSym+"}{dt}="+govFunc+end_term;
                    end
                elseif k > length(comps)-5 && k <= length(comps)
                    funcSym = app.system.getCompSymByName(comps{k}.getName());
                    govFunc = app.system.getGovFuncByCompName(comps{k}.getName());
                    if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                    app.SummaryL.Text = app.SummaryL.Text + "\frac{d"+funcSym+"}{dt}="+govFunc+end_term;
                else
                    funcSym = uni2latex(app.system.getEnvAndHelperSymByName(helperNames(k-length(comps))));
                    govFunc = app.system.getEnvAndHelperFuncValByName(helperNames(k-length(comps)));
                    if k == length(comps) + length(helperNames), end_term = "$"; else, end_term = " \\"; end
                    app.SummaryL.Text = app.SummaryL.Text + string(funcSym)+"="+string(govFunc)+end_term;
                end
            end
            % resize/reposition SummaryL
            app.SummaryL.Position = [0,0,app.SummaryL.Position(3),app.SummaryL.Position(4)];
        end

        function updatePlotUI(app,plot)
            app.PlotTitleF.Value = plot{1};
            if plot{3} == 2
                app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            elseif plot{3} == 3
                app.NbAxesDD.Value = app.NbAxesDD.Items{2};
            end
            app.PlotDirL.Text = "Directory: "+plot{4};
            if (any(convertCharsToStrings(app.FileTypeDD.Items) == plot{5}))
                app.FileTypeDD.Value = plot{5};
            else
                app.FileTypeDD.Items = [app.FileTypeDD.Items,plot{5}];
                app.FileTypeDD.Value = plot{5};
            end
            axesNames = cell(1,length(plot{2}));
            for k=1:1:length(axesNames)
                axesNames{k} = (plot{2}{k}.title);
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
            
            % remember to update uitree for plot generation
            nodeExists = false;
            for k=1:1:length(app.plotDispTreeNodes)
                if app.plotDispTreeNodes{k}.name == plot{1}
                    app.plotDispTreeNodes{k}.main.Text = plot{1};
                    app.plotDispTreeNodes{k}.display.NodeData = plot{6};
                    app.plotDispTreeNodes{k}.download.NodeData = plot{7};
                    nodeExists = true;
                end
            end
            if ~nodeExists
                main = uitreenode(app.PlotDispTree,"Text",plot{1});
                display = uitreenode(main,"Text","Display","NodeData",plot{6});
                download = uitreenode(main,"Text","Download","NodeData",plot{7});
                app.plotDispTreeNodes{end+1} = struct('name',plot{1}, ...
                    'main',main,'display',display,'download',download);
            end
        end

        function updateAxesUI(app,axes)
            if length(axes) < 3
                app.ZAxTitleF.Value = "";
                app.ZAxVarDD.Items = {};
                app.ZAxVarDD.Value = "";
                app.ZAxLoLimVF.Value = 1;
                app.ZAxUpLimVF.Value = 100;
                app.ZAxDefaultRCB.Value = false;
                app.ZAxTitleF.Enable = false;
                app.ZAxVarDD.Enable = false;
                app.ZAxLoLimVF.Enable = false;
                app.ZAxUpLimVF.Enable = false;
                app.ZAxDefaultRCB.Enable = false;
                app.ZAxVarAB.Enable = false;
                app.ZAxVarRB.Enable = false;
                app.ZAxVarL.Enable = false;
                app.ZAxDispLimUB.Enable = false;
            else
                app.ZAxTitleF.Enable = true;
                app.ZAxVarDD.Enable = true;
                app.ZAxLoLimVF.Enable = true;
                app.ZAxUpLimVF.Enable = true;
                app.ZAxDefaultRCB.Enable = true;
                app.ZAxVarAB.Enable = true;
                app.ZAxVarRB.Enable = true;
                app.ZAxVarL.Enable = true;
                app.ZAxDispLimUB.Enable = true;
            end
            for k=1:1:length(axes)
                if k == 1
                    dir = "X";
                elseif k == 2
                    dir = "Y";
                elseif k == 3
                    dir = "Z";
                end
                app.(dir+"AxTitleF").Value = axes{k}{2};
                app.(dir+"AxVarDD").Items = [axes{k}{4};axes{k}{15}];
                app.(dir+"AxVarDD").Value = axes{k}{3};
                app.(dir+"AxVarL").Text = "Variable(s): $"+axes{k}{3}+"$";
                app.(dir+"AxLoLimVF").Value = axes{k}{5};
                app.(dir+"AxUpLimVF").Value = axes{k}{6};
                app.(dir+"AxDefaultRCB").Value = axes{k}{7};

                if k == 3
                    app.AxVarUBButtonPushed(struct('Source',app.ZAxVarAB));
                end
            end
        end

        function removeRegParamsByComp(app,compName)
            params = app.system.removeRegParamByComp(compName);
            app.RegParamT.ColumnName = {'Parameter','Component','Function','Value','Regressed Value'};
            app.RegParamT.Data = params;
        end

        function reinstantiateODESysUI(app)
            % Home Tab
            app.ModelNameTA.Value = app.system.save_model_name;
            app.ModelDescHTA.Value = app.system.save_model_desc;
            if length(app.system.save_model_bio_names) > length(app.system.save_model_chem_names)
                app.system.save_model_chem_names(length(app.system.save_model_chem_names)+1: ...
                    length(app.system.save_model_chem_names)+(length(app.system.save_model_bio_names)- ...
                    length(app.system.save_model_chem_names)-1)) = {'-'};
            elseif length(app.system.save_model_chem_names) > length(app.system.save_model_bio_names)
                app.system.save_model_bio_names(length(app.system.save_model_bio_names)+1: ...
                    length(app.system.save_model_bio_names)+(length(app.system.save_model_chem_names)- ...
                    length(app.system.save_model_bio_names)-1)) = {'-'};
            end
            app.ModelBioChemT.Data = [app.system.save_model_bio_names,app.system.save_model_chem_names];
            app.RefHTA.Value = app.system.save_model_ref_str;

            % Environment Tab
            app.IncidentLightFuncVF.Value = app.system.getEnvAndHelperFuncValByName('Incident Light');
            [reactorType,params] = app.system.getReactorSpecificParams();
            app.ReactorTypeDD.Value = reactorType;
            app.ReactorTypeDDValueChanged();
            % app.
            app.GenParamDD.Value = app.GenParamDD.Items(1);
            [paramVals,~] = app.system.getCurrentEnvironParams();
            app.GenParamF.Value = paramVals{1};

            % Species Tab


            % Mass Transfer Tab


            % Governing Functions Tab


            % Parameters Tab


            % Regression Tab


            % Summary Tab


            % Plotting Tab
            

        end
    end

    methods (Access = public)
        function updateImportedData(app,filePath,importedData,colNames)
            app.system.setImportedData(filePath,importedData,colNames);
            
            app.ImportedDataDD.Items = string(colNames);
        end

        function updateRegSpecs(app,regSpecs)
            app.system.setRegSpecs(regSpecs);
        end

        function runNRMSE(app,normType)
            app.RegAnalysisWindow.dispNRMSE(app.system.calcNRMSE(normType));
        end

        function runParCI(app,calcType,alpha)
            app.RegAnalysisWindow.dispParCI(app.system.calcParCI(calcType,alpha));
        end

        function runPredCI(app,tval,calcType,alpha,simBnds,predIntType)
            app.RegAnalysisWindow.dispPredCI(app.system.calcPredCI(tval,calcType,alpha,simBnds,predIntType));
        end
    end

    methods (Static)
        function newItems = addItemToDD(items,newVal)
            if any(strcmp(items,newVal))
                newItems = items;
            else
                newItems = [items,newVal];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.system = ODESys();

            [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
            app.EnvironT.Data = [app.envParamNames,paramVals,paramUnits];
            app.GenParamF.Value = app.EnvironT.Data{1,2};
        end

        % Callback function
        function RunButtonPushed(app, event)
            [tRes,yRes] = app.modelHandler();
%             disp(yRes)
%             disp(tRes)
            plot(app.TestPlot,tRes,yRes(:,1:1:2));
            xlabel(app.TestPlot,'Time (hrs)'); ylabel(app.TestPlot,'Cell Density (g/L)');
            title(app.TestPlot, 'SE and MA');
            xlim(app.TestPlot, [0,tRes(end)]); ylim(app.TestPlot, [0,max(yRes(:,1),yRes(:,2))]);
            legend(app.TestPlot,{'SE';'MA'});
        end

        % Callback function
        function GrthTimeRunButtonPushed(app, event)
            [tRes,yRes] = app.modelHandler();
            plot(app.TestPlot,tRes,yRes(:,1:1:2));
            xlabel(app.TestPlot,'Time (hrs)'); ylabel(app.TestPlot,'Cell Density (g/L)');
            title(app.TestPlot, 'Cell Density vs Model Run Time');
            xlim(app.TestPlot, [0,tRes(end)]); ylim(app.TestPlot, [0,max([yRes(:,1);yRes(:,2)])]);
            legend(app.TestPlot,{'SE','MA'});
        end

        % Callback function
        function SpecParamValChanged(app, event)
            value = app.MaxGrowthRateEditField.Value;
            if event.Source.tag == "maxGrthRt"
                
            end
        end

        % Button pushed function: SpecAB
        function SpecABPushed(app, event)
            % get species name input
            specName = app.SpecNameDD.Value;
            if ~any(strcmp(app.SpecNameDD.Items,specName))
                app.SpecNameDD.Items{end+1} = specName;
            end
            % get species initial concentration input
            specInitConc = app.SpecInitConcF.Value;
            % get species init conc unit input
            specInitConcUnit = app.SpecInitConcUnitDD.Value;

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.SpecT.Data{k,1};
            end

            % convert to g/L
            if specInitConcUnit == "g/L"
                specInitDispConc = specInitConc;
            elseif specInitConcUnit == "mg/L"
                specInitDispConc = specInitConc;
                specInitConc = specInitConc/(1E3);
            elseif specInitConcUnit == "ug/L"
                specInitDispConc = specInitConc;
                specInitConc = specInitConc/(1E6);
            end

            % adds new Species to display table
            if any(strcmp(TNames,specName))
                updIdx = strcmp(TNames, specName);
                % updated Species table data
                app.SpecT.Data(updIdx,:) = {specName, specInitDispConc, specInitConcUnit};
                % update Species in ODESys
                app.system.updateSpecies(specName, "initConc", specInitConc);
            else
                % new Species table data
                app.SpecT.Data = [app.SpecT.Data; {specName, specInitDispConc, specInitConcUnit}];
                % add new Species to ODESys
                app.system.addSpecies(length(TNames)+1, specName, specInitConc);
            end

            % setting SpecNameDD.Items
            app.SpecNameDD.Items = app.SpecT.Data(:,1);

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getAllBioChemSolutes();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.MTSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegCompNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();
        end

        % Button pushed function: SpecRB
        function SpecRBButtonPushed(app, event)
            specName = app.SpecNameDD.Value;
            if ~any(strcmp(app.SpecNameDD.Items,specName))
                return;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.SpecT.Data{i,2};
            end

            % removes species from display table
            if any(strcmp(TNames,specName))
                rmIdx = strcmp(TNames, specName);
                app.SpecT.Data(rmIdx,:) = [];
            else
                return;
            end

            % resets numbering of Speices in table
            for i=1:1:height(app.SpecT.Data)
                app.SpecT.Data{i,1} = i;
            end

            % removing Species from ODESys
            app.system.removeSpecies(specName);

            % updating SpecNameDD.Items
            app.SpecNameDD.Items = app.SpecT.Data(:,1);
            try app.SpecNameDD.Value = app.SpecNameDD.Items(1); catch err, disp(err); end

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getAllBioChemSolutes();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.MTSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegCompNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();
        end

        % Value changed function: GenParamDD
        function GenParamDDValueChanged(app, event)
            value = app.GenParamDD.Value;
            switch value
                case "Model Runtime (t)"
                    app.GenParamUnitDD.Items = {'days','hrs','min','sec'};
                    app.GenParamUnitDD.Value = 'hrs';
                    app.EnvironParamFuncL.Text = "$t =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{1,2});
                    [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                    I_func = matlabFunction(str2sym(paramVals{2}));
                    t_span = linspace(0,str2double(paramVals{1}),100);
                    if contains(func2str(I_func),'t')
                        plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                    else
                        plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                    end
                    title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                    xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    ylabel(app.EnvironParamFuncPlot,"Incident Light ("+string(paramUnits{2})+")");
                case "Incident Light (I_0)"
                    app.GenParamUnitDD.Items = {'ppf'};
                    app.EnvironParamFuncL.Text = "$I(t) =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{2,2});
                case "Initial Temperature (T_0)"
                    app.GenParamUnitDD.Items = {'C','K','F','R'};
                    app.EnvironParamFuncL.Text = "$T_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{3,2});
                case "Initial Pressure (P_0)"
                    app.GenParamUnitDD.Items = {'kPa','Pa'};
                    app.EnvironParamFuncL.Text = "$P_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{4,2});
                case "Initial Culture Volume (V_0)"
                    app.GenParamUnitDD.Items = {'L','m^3'};
                    app.EnvironParamFuncL.Text = "$V_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{5,2});
                case "Initial pH (pH_0)"
                    app.GenParamUnitDD.Items = {'N/A'};
                    app.EnvironParamFuncL.Text = "$pH_0 =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{6,2});
            end
        end

        % Button pushed function: EnvGeomUB, GenParamAB
        function GenParamABButtonPushed(app, event)
            paramVal = app.GenParamF.Value;
            paramName = app.GenParamDD.Value;
            paramUnits = app.GenParamUnitDD.Value;

            lightFunc = app.IncidentLightFuncVF.Value;

            reactorType = app.ReactorTypeDD.Value;

            % 1. updating table values
            % 2. unit conversions before input to ODESys
            % envParamNames = {'Model Run Time (t)';'Incident Light (I_0(t))';'Maximum Volume (V_m)';'Temperature (T(t))';'Pressure (P(t))'; ...
            % 'Initial Culture Volume (V_0)';'Initial pH (pH_0)';'Agitation Speed';'Tank Height';'Tank Diameter'; ...
            % 'Tank Surface Area';'Light Configuration';'# Light Sources';'Light Source Diameter';'Light Source Circle Radius'};
            switch paramName
                case "Model Runtime (t)"
                    app.EnvironT.Data(1,2) = {char(string(paramVal))};
                    app.EnvironT.Data(1,3) = {paramUnits};
                    switch paramUnits
                        case "hr"                            
                        case "min"
                            paramVal = paramVal ./ 60;
                        case "sec"
                            paramVal = paramVal ./ (60.*60);
                        case "day"
                            paramVal = paramVal .* 24;
                    end
                case "Initial Temperature (T_0)"
                    app.EnvironT.Data(3,2) = {char(string(paramVal))};
                    app.EnvironT.Data(3,3) = {paramUnits};
                    switch paramUnits
                        case "C"                            
                        case "K"
                            paramVal = paramVal - 273.15;
                        case "F"
                            paramVal = (paramVal - 32) * (5/9);
                        case "R"
                            paramVal = ((paramVal - 32) * (5/9)) - 459.67;
                    end
                case "Initial Pressure (P_0)"
                    app.EnvironT.Data(4,2) = {char(string(paramVal))};
                    app.EnvironT.Data(4,3) = {paramUnits};
                    switch paramUnits
                        case "kPa"
                        case "Pa"
                            paramVal = paramVal .* 1000;
                    end
                case "Initial Culture Volume (V_0)"
                    app.EnvironT.Data(5,2) = {char(string(paramVal))};
                    app.EnvironT.Data(5,3) = {paramUnits};
                    switch paramUnits
                        case "L"
                        case "m^3"
                            paramVal = paramVal ./ 1000;
                        case "%"
                            paramVal = (paramVal./100) .* app.system.PBR_max_vol;
                    end
                case "Initial pH (pH_0)"
                    app.EnvironT.Data(6,2) = {char(string(paramVal))};
                    app.EnvironT.Data(6,3) = {paramUnits};
            end

            app.EnvironT.Data(2,1) = {'Incident Light (I_0)'};
            if isempty(lightFunc), lightFunc = 100; end
            app.EnvironT.Data(2,2) = {char(string(lightFunc))};
            app.EnvironT.Data(2,3) = {'ppf'};
            I_func = "@(t)"+lightFunc;
            I_func = str2func(replace(replace(replace(I_func,'*','.*'),'/','./'),'^','.^'));
            t_span = linspace(0,1000,10000);
            if contains(lightFunc,'t')
                plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
            else
                plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
            end
            title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
            xlabel(app.EnvironParamFuncPlot,"Model Runtime (hr)");
            ylabel(app.EnvironParamFuncPlot,"Incident Light (ppf)");
            app.system.updateEnvironment('Incident Light (I_0(t))',lightFunc);

            % reactor geometry-specific parameters
            switch reactorType
                case "Vertical Continuous Stirred Tank"
                    agSpd = app.ReactParam1VF.Value;
                    agSpdU = app.ReactParamUnit1DD.Value;
                    switch agSpdU
                        case "rpm"
                        case "rad/s"
                            agSpd = agSpd ./ (2.*pi.*60);
                    end
                    tkHt = app.ReactParam2VF.Value;
                    tkDiam = app.ReactParam3VF.Value;
                    tkHtU = app.ReactParamUnit2DD.Value;
                    switch tkHtU
                        case "m"
                        case "cm"
                            tkHt = tkHt ./ 100;
                        case "mm"
                            tkHt = tkHt ./ 1000;
                    end
                    tkDiamU = app.ReactParamUnit3DD.Value;
                    switch tkDiamU
                        case "m"
                        case "cm"
                            tkDiam = tkDiam ./ 100;
                        case "mm"
                            tkDiam = tkDiam ./ 1000;
                    end
                    tkMaxVol = (tkHt.*pi.*(tkDiam./2).^2).*1000;
                    tkSA = tkHt.*2.*pi.*(tkDiam./2) + pi.*(tkDiam./2).^2;

                    lgtConfig = app.ReactParam5DD.Value;
                    lgtConfigU = '';
                    numLgtSrc = app.ReactParam6VF.Value;
                    numLgtSrcU = '';
                    lgtSrcDiam = app.ReactParam7VF.Value;
                    lgtSrcDiamU = app.ReactParamUnit7DD.Value;
                    switch lgtSrcDiamU
                        case "m"
                        case "cm"
                            lgtSrcDiam = lgtSrcDiam ./ 100;
                        case "mm"
                            lgtSrcDiam = lgtSrcDiam ./ 1000;
                    end
                    lgtSrcCircRad = app.ReactParam8VF.Value;
                    lgtSrcCircRadU = app.ReactParamUnit8DD.Value;
                    switch lgtSrcCircRadU
                        case "m"
                        case "cm"
                            lgtSrcCircRad = lgtSrcCircRad ./ 100;
                        case "mm"
                            lgtSrcCircRad = lgtSrcCircRad ./ 1000;
                    end
                    
                    reactorSpecificParamN = {'Agitation Speed';'Tank Height';'Tank Diameter';'Max Tank Volume';'Tank Surface Area';'Light Configuration'; ...
                        '# Light Sources';'Light Source Diameter';'Light Source Circle Radius'};
                    reactorSpecificParams = {agSpd;tkHt;tkDiam;tkMaxVol;tkSA;lgtConfig;numLgtSrc;lgtSrcDiam;lgtSrcCircRad};
                    reactorSpecificParamU = {agSpdU;tkHtU;tkDiamU;'L';'m^2';lgtConfigU;numLgtSrcU;lgtSrcDiamU;lgtSrcCircRadU};

                    environ_vars = struct( ...
                        'agSpd',agSpd, ...
                        'tkHt',tkHt, ...
                        'tkDiam',tkDiam, ...
                        'tkMaxVol',tkMaxVol, ...
                        'tkSA',tkSA, ...
                        'lgtConfig',lgtConfig, ...
                        'numLgtSrc',numLgtSrc, ...
                        'lgtSrcDiam',lgtSrcDiam, ...
                        'lgtSrcCircRad',lgtSrcCircRad ...
                    );

                    % updating environment table display
                    app.EnvironT.Data((7):(6+length(reactorSpecificParams)),:) = ...
                        [reactorSpecificParamN,reactorSpecificParams,reactorSpecificParamU];

                case "Horizontal Plug Flow Reactor"

                case "Flat Panel"

                case "Raceway Pond"

                case "Shaking Flask"

                case "Benchtop Bioreactor"

                case "Eutrophic Lake System"

            end
            % updating reactor specific environment parameters in ODESys
            app.system.updateEnvironment("reactorSpecificParams",{reactorType,reactorSpecificParams});

            % update environment parameters in ODESys
            app.system.updateEnvironment(paramName, paramVal);
            app.system.updatePBRMaxVol(tkMaxVol*1000);
            app.system.environ_vars = environ_vars;

            % updates GovFunc var table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            app.RegSysVarDD.Items = varNames(:,1);

            % ### UPDATE: add light helper function based on incident light
            % helperFunc = "I_0*exp(0)"


            % Plotting for incident light
            if strcmp(paramName,"Incident Light (I_0)")
                [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                I_func = str2func("@(t)"+paramVals{1});
                t_span = linspace(0,100,100);
                if contains(paramVals{1},'t')
                    plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                else
                    plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                end
                title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                xlabel(app.EnvironParamFuncPlot,"Model Runtime");
                ylabel(app.EnvironParamFuncPlot,"Incident Light ("+paramUnits{1}+")");
            end

            app.system.compileModel();
        end

        % Button pushed function: ChemAB
        function ChemABPushed(app, event)
            % get chemical name input
            chemName = app.ChemNameDD.Value;
            % get chemical initial concentration input
            chemInitConc = app.ChemInitConcF.Value;
            % get chemical init conc unit input
            chemInitConcUnit = app.ChemInitConcUnitDD.Value;

            is_vol = app.VolCB.Value;
            type = app.ChemTypeDD.Value;
            MW = double(string(app.MWVF.Value));
            h_const = double(string(app.HConstVF.Value));
            h_const_u = double(string(app.HConstUDD.Value));
            dh_const = double(string(app.HConst2VF.Value));
            dh_const_u = double(string(app.HConst2UDD.Value));

            if is_vol, is_vol_disp = 'Yes'; else, is_vol_disp = 'No'; end
            h_const_disp = h_const;
            dh_const_disp = dh_const;
            if ~is_vol
                h_const = 1;
                h_const_u = '';
                dh_const = 1;
                dh_const_u = '';
                h_const_disp = 'N/A';
                dh_const_disp = 'N/A';
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for k=1:1:length(TNames)
                TNames{k} = app.ChemT.Data{k,1};
            end

            % convert to g/L
            if chemInitConcUnit == "g/L"
                chemInitDispConc = chemInitConc;
            elseif chemInitConcUnit == "mg/L"
                chemInitDispConc = chemInitConc;
                chemInitConc = chemInitConc/(1E3);
            elseif chemInitConcUnit == "ug/L"
                chemInitDispConc = chemInitConc;
                chemInitConc = chemInitConc/(1E6);
            end

            % adds new Chemical to display table
            if any(strcmp(TNames,chemName))
                % updated Chemical table data
                updIdx = strcmp(TNames, chemName);
                app.ChemT.Data(updIdx,:) = {chemName, type, is_vol_disp, chemInitDispConc, chemInitConcUnit, ...
                    MW, h_const_disp, h_const_u, dh_const_disp, dh_const_u};
                % update Chemical in ODESys
                app.system.updateChemical(chemName,chemInitConc,is_vol,MW,h_const,h_const_u,dh_const,dh_const_u);
            else
                % new Chemical table data
                app.ChemT.Data = [app.ChemT.Data; {chemName, type, is_vol_disp, chemInitDispConc, chemInitConcUnit, ...
                    MW, h_const_disp, h_const_u, dh_const_disp, dh_const_u}];
                % add new Chemical to ODESys
                app.system.addChemical(length(TNames)+1,chemName,chemInitConc,type,is_vol,MW,h_const,h_const_u,dh_const,dh_const_u);
            end

            % updating ChemNameDD.Items
            app.ChemNameDD.Items = app.ChemT.Data(:,1);

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getAllBioChemSolutes();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.MTSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegCompNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();
        end

        % Button pushed function: ChemRB
        function ChemRBButtonPushed(app, event)
            chemName = app.ChemNameDD.Value;
            if chemName == "Custom Chemical"
                chemName = app.ChemCustNameF.Value;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.ChemT.Data{i,2};
            end

            % removes chemical from display table
            if any(strcmp(TNames,chemName))
                rmIdx = strcmp(TNames, chemName);
                app.ChemT.Data(rmIdx,:) = [];
            else
                return;
            end

            % resets numbering of Chemical in table
            for i=1:1:height(app.ChemT.Data)
                app.ChemT.Data{i,1} = i;
            end

            % removing Chemical from ODESys
            app.system.removeChemical(chemName);

            % updating ChemNameDD.Items
            app.ChemNameDD.Items = app.ChemT.Data(:,1);
            try app.ChemNameDD.Value = app.ChemNameDD.Items(1); catch err, disp(err); end

            % InteractCompDD.Items should only be biological species and
            % the liquid phase chemical species
            varNames1 = app.system.getInteractCompDDItems();
            app.InteractCompDD.Items = varNames1(:,1);

            % MTCompDD.Items and CompSoluteDDs.Items should only be chemical solute species
            app.MTCompDD.Items = app.system.getSoluteChems('name',true);
            app.CompSoluteDD.Items = app.system.getSoluteChems('name',false);

            % CompParamDD.Items, RegCompParamDD.Items, RegSysVarDD.items, PlotDVDD.Items, 
            % should be all biological/chemical
            % solute species in all phases, and environmental comps
            varNames2 = app.system.getAllBioChemSolutes();
            app.CompParamDD.Items = varNames2(:,1);

            % SysVarT.Data and MTSysVarT.Data should be all biological/chemical 
            % solute species in all phases, environmental comps, and helper
            % functions
            varNames3 = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames3;
            app.MTSysVarT.Data = varNames3;
            varNames4 = app.system.getModelVarNames("plot");
            app.RegSysVarDD.Items = varNames4(:,1);
            varNames5 = app.system.getRegCompNames();
            app.RegCompParamDD.Items = varNames5(:,1);
            app.InteractCompDDValueChanged();
            app.MTCompDDValueChanged();
            app.CompSoluteDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.system.compileModel();
        end

        % Value changed function: ChemNameDD
        function ChemNameDDValueChanged(app, event)
            chemName = app.ChemNameDD.Value;
            % ### STARTHERE: if the chemname is in chems that are already
            % existing in model, and the type is suspended solids, then
            % activate sorbent equilibrium stuff, else deactivate
        end

        % Value changed function: CompParamDD
        function CompParamDDValueChanged(app, event)
            compName = app.CompParamDD.Value;

            if isempty(compName), return; end

            compNames = [app.system.getCompNamesByType("Biological Solute",false), ...
                app.system.getCompNamesByType("Chemical Solute",false), ...
                app.system.getCompNamesByType("Environ",false)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                % getting growth parameter data from Species
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end
            
            % ### STARTHERE: Pressure has a bunch of random params that
            % shouldn't be params? If a function has no params, it throws
            % an error
            % test5 = params
            % updating the data in GrthParamT
            test3 = params
            app.ParamT.Data = params;
            % ### UPDATE: add logic here to restrict users from editing
            % values for R and tau
            app.ParamNumDD.Items = params(:,1);
            app.ParamSymDD.Items = params(:,4);
            if length(app.ParamNumDD.Items) > 0 %#ok<ISMT> 
                app.ParamNumDD.Value = app.ParamNumDD.Items(1);
            end
            if length(app.ParamSymDD.Items) > 0 %#ok<ISMT>
                app.ParamSymDD.Value = app.ParamSymDD.Items(1);
            end
%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(params(:,4))
%             test = app.ParamT.Data
            % ### FIXMEUI: try and catch
            try
                app.ParamNF.Value = app.ParamT.Data{1,3};
                app.ParamValF.Value = app.ParamT.Data{1,5};
                app.ParamUnitDD.Value = app.ParamT.Data{1,6};
            catch err
                disp(err);
            end
        end

        % Button pushed function: ParamUB
        function ParamUBPushed(app, event)
            compName = app.CompParamDD.Value;
            paramSym = app.ParamSymDD.Value;
            paramNum = str2double(app.ParamNumDD.Value);
            newParamName = app.ParamNF.Value;
            newParamVal = app.ParamValF.Value;
            newParamUnit = app.ParamUnitDD.Value;
            
            compNames = [app.system.getCompNamesByType("Biological Solute",false),app.system.getCompNamesByType("Chemical Solute",false)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                funcName = app.ParamT.Data{paramNum,2};
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            if ~any(strcmp([app.ParamUnitDD.Items{:}],newParamUnit))
                app.ParamUnitDD.Items = [app.ParamUnitDD.Items,{newParamUnit}];
            end

            % updating the data in GrthParamT
            app.ParamT.Data = params;

            app.system.compileModel();
        end

        % Value changed function: ParamSymDD
        function ParamSymDDValueChanged(app, event)
            paramSym = app.ParamSymDD.Value;
            % ### FIXME: if 2 params of the same name, won't automatically
            % adjust editor values when the parameter symbol is changed
            idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym);
            app.ParamNumDD.Value = app.ParamNumDD.Items{idx};
            app.ParamNF.Value = app.ParamT.Data{idx,3};
            app.ParamValF.Value = app.ParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.ParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: ParamNumDD
        function ParamNumDDValueChanged(app, event)
            paramNum = app.ParamNumDD.Value;
            idx = [app.ParamT.Data{:,1}] == paramNum;
            app.ParamSymDD.Value = app.ParamSymDD.Items{idx};
            app.ParamNF.Value = app.ParamT.Data{idx,3};
            app.ParamValF.Value = app.ParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.ParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.ParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: InteractCompDD
        function InteractCompDDValueChanged(app, event)
            compName = app.InteractCompDD.Value;

            if isempty(compName), return; end

            % update the displayed governing function
            compSym = app.system.getCompSymByName(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";

            % enables piecewise editors
            app.PiecewiseLimsP.Enable = true;

            % disables ModelUB
            app.ModelUB.Enable = false;

            app.CompParamDD.Value = compName;
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
        end

        % Button pushed function: ModelUB
        function ModelUBButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            funcVal = app.GovFuncVF.Value;

            % save the new function to the component
            app.system.updateModel(compName,funcVal,'');

            % set governing func saved status to "Saved"
            app.SaveL.Text = "Saved";

            % enables relevant editors
            app.PiecewiseLimsP.Enable = true;

            % disables ModelUB, reenable when there is change
            app.ModelUB.Enable = false;

            % getting growth parameter data from Species
            params = app.system.getGrthParamsByCompName(compName);
            % updating the data in GrthParamT
            app.ParamT.Data = params;

            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;

            % ### UPDATE: automatically remove any piecewise expressions
            % that no longer exist in the governing function


            % updating the Param editors with CompParamDD callback
            idx = regexp(compName,'\(');
            compName = char(compName);
            if ~isempty(idx), compName = compName(1:idx-2); end
            app.CompParamDD.Value = compName;
            app.CompParamDDValueChanged();
            % getting growth parameter data for regression
            app.RegCompParamDDValueChanged();

            % updates SumL text
            app.updateSumL();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();

            app.ModelFunctionsPanel.Scrollable = "on";
        end

        % Callback function
        function ModelRBButtonPushed(app, event)
            if ~isempty(app.SubFuncDD.Items)
                compName = app.InteractCompDD.Value;
                app.system.removeModel();
    
                % updates Gov Func display
                app.FuncDispBValueChanged();
    
                % enables SubFuncDD
                app.SubFuncDD.Enable = true;
    
                % update SubFuncDD
                app.SubFuncDD.Value = app.SubFuncDD.Items{1};
                items = convertCharsToStrings(app.SubFuncDD.Items);
                items(items == app.SubFuncNF.Value) = [];
                app.SubFuncDD.Items = items;
                app.SubFuncDDValueChanged();

                % getting growth parameter data from Species
                params = app.system.getGrthParamsByCompName(compName);
                % updating the data in GrthParamT
                app.ParamT.Data = params;
    
                % updating the Param editors with CompParamDD callback
                app.CompParamDDValueChanged();
                % getting growth parameter data for regression
                app.RegCompParamDDValueChanged();

                % updates MSumL text
                app.updateSumL();
            end
        end

        % Callback function
        function FuncDispBValueChanged(app, event)
            value = app.FuncDispB.Value;
            compName = app.InteractCompDD.Value;
            compSym = app.system.getCompSymByName(compName);
            if value == false
                app.FuncDispB.Text = "Function Names";
                if app.SaveL.Text == "Not Saved"
                    app.SubFuncNFValueChanging(app.SubFuncNF);
                else
                    existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Names");
                    existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Names");
                    for k=1:1:length(existFuncSum)
                        if existFuncSum(k) ~= ""
                            existFuncSum(k) = "+"+existFuncSum(k);
                        end
                    end
                    app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);
                end
            else
                app.FuncDispB.Text = "Function Values";
                if app.SaveL.Text == "Not Saved"
                    app.SubFuncVFValueChanging(app.SubFuncVF);
                else
                    existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Values");
                    existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Values");
                    for k=1:1:length(existFuncSum)
                        if existFuncSum(k) ~= ""
                            existFuncSum(k) = "+"+existFuncSum(k);
                        end
                    end
                    app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);
                end
            end
        end

        % Callback function
        function FuncComboDDValueChanged(app, event)
            compName = app.InteractCompDD.Value;
            funcCombo = app.FuncComboDD.Value;
            app.FuncTypeDD.Items = app.system.getValidFuncs(compName,funcCombo);
            app.FuncTypeDD.Value = app.FuncTypeDD.Items{1};

            % setting SubFuncDD Items
            app.SubFuncDD.Items = app.system.getCompFuncNames(compName,funcCombo);

            % setting SubFuncNF and SubFuncVF values
            if isempty(app.SubFuncDD.Items)
                app.SubFuncNF.Value = "";
                app.SubFuncVF.Value = "";
            else
                app.SubFuncNF.Value = app.SubFuncDD.Value;
                app.SubFuncVF.Value = app.system.getCompFuncByFuncName(compName,app.SubFuncNF.Value);
            end

            % setting limit editor values
            

            % update SubFuncDD
            app.SubFuncDDValueChanged();
        end

        % Callback function
        function PlotTitleAxUBButtonPushed(app, event)
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
                app.AxVarUBButtonPushed(struct('Source',app.YAxVarAB));
            end
            % ### FIXME: add code to disable Z-axis if only 2 axes
            % specified
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
            items = convertCharsToStrings(app.PlotTitleDD.Items);
            items(items == app.PlotTitleDD.Value) = title;
            app.PlotTitleDD.Items = ~strcmp(items,"");
            app.PlotTitleDD.Value = title;

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotAB
        function PlotABButtonPushed(app, event)
            % ### FIXME: convert to more efficient plot data getting
            [plot,axes] = app.system.createNewPlot();
            app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            app.updatePlotUI(plot);
            app.PlotTitleDD.Items = [app.PlotTitleDD.Items,plot{1}];
            app.PlotTitleDD.Value = plot{1};
%             app.(dir+"AxVarL").Text = "Variable(s): "+varName;

            % setting default axes editor values
            app.updateAxesUI(axes);
        end

        % Callback function
        function PlotRBButtonPushed(app, event)
            % update title, axes number, save as function, generation
            % options, initial condition stuff
            plotName = app.PlotTitleDD.Value;
            if length(app.PlotTitleDD.Items) > 1 
                [plot,axes] = app.system.removePlot(plotName,false);
                app.updatePlotUI(plot);
                items = convertCharsToStrings(app.PlotTitleDD.Items);
                items(items == plotName) = [];
                app.PlotTitleDD.Items = ~strcmp(items,"");
                app.PlotTitleDD.Value = app.PlotTitleDD.Items{1};
                for k=1:1:length(app.plotDispTreeNodes)
                    if app.plotDispTreeNodes{k}.name == plotName
                        app.plotDispTreeNodes{k}.main.delete();
                        app.plotDispTreeNodes{k}.display.delete();
                        app.plotDispTreeNodes{k}.download.delete();
                        app.plotDispTreeNodes(k) = [];
                        break;
                    end
                end
                app.updateAxesUI(axes);
            else
                % code to reset entire plot customization tab to default,
                % remove last item
                [plot,axes] = app.system.removePlot(plotName,true);
            end
        end

        % Value changed function: ICEvalAxisDD
        function ICEvalAxisDDValueChanged(app, event)
            axisName = app.ICEvalAxisDD.Value;
            plotName = app.PlotTitleF.Value;
            % 1 - Vars, 2 - Eval Var at t, 3 - Lower Eval Lim, 4 - Upper
            % Eval Lim, 5 - Number of Eval Points, 6 - isDV (boolean), 7 -
            % varIsIC (boolean)
            ICEvalData = app.system.getAxisICEvalData(plotName,axisName);
            app.ICEvalVarDD.Items = ICEvalData{1};
            app.ICEvalVarDD.Value = app.ICEvalVarDD.Items{1};

            app.ICEvalVarDDValueChanged();
        end

        % Button pushed function: XAxDispLimUB, YAxDispLimUB, ZAxDispLimUB
        function AxDispLimUBButtonPushed(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            loDispLim = app.(dir+"AxLoDispLimVF").Value;
            upDispLim = app.(dir+"AxUpDispLimVF").Value;
            useDefR = app.(dir+"AxDefaultRCB").Value;
            app.system.updateAxDispLims(plotName,dir,loDispLim,upDispLim,useDefR);
        end

        % Button pushed function: PlotDownloadDirB
        function PlotDownloadDirBButtonPushed(app, event)
            dirName = uigetdir;
            plotName = app.PlotTitleDD.Value;
            app.system.setPlotDir(plotName,dirName);
            app.PlotDirL.Text = "Directory: "+dirName;
        end

        % Button pushed function: GenPlotB
        function GenPlotBButtonPushed(app, event)
            if app.ClosePrevPlotsCB.Value
                close all;
            end

            app.system.generatePlots();
        end

        % Callback function
        function PlotDispTreeCheckedNodesChanged(app, event)
            % ### FIXME: issue with Parent node being switched if the
            % actual parent is not checked, this causes inaccuracies.
            nodes = app.PlotDispTree.CheckedNodes;
            app.system.clearPlotDispDownload();
            
            for k=1:1:length(nodes)
                if nodes(k).Text == "Display"
                    disp("worked")
                    prop = "display";
                    plotName = nodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                elseif nodes(k).Text == "Download"
                    prop = "download";
                    plotName = nodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                else
                    plotName = nodes(k).Text;
                    app.system.updatePlotDispDownload(plotName,"display",true);
                    app.system.updatePlotDispDownload(plotName,"download",true);
                end
            end
        end

        % Button pushed function: XAxVarAB, XAxVarRB, YAxVarAB, YAxVarRB, 
        % ...and 2 other components
        function AxVarUBButtonPushed(app, event)
            % ### FIXME: add functionality to update whether selected
            % variable is a set of IC
            % add functionality to update the options for the ranges of max
            % and min
            tag = split(event.Source.Tag,"_");
            dir = tag(1);
            if tag(2) == "add"
                addVar = true;
            else
                addVar = false;
            end
            plotName = app.PlotTitleF.Value;
            varName = string(app.(dir+"AxVarDD").Value);
            app.(dir+"AxVarL").Text = app.system.updateAxVars(plotName,dir,varName,addVar);
            app.ICEvalAxisDDValueChanged();   
        end

        % Callback function
        function AxTitleUpdate(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            title = app.(dir+"AxTitleF").Value;
            axesUI = app.system.updateAxTitle(plotName,dir,title);
            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotICEvalSpecUB
        function PlotICEvalSpecUBButtonPushed(app, event)
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            varUnits = app.evalUnitsVF.Value;
            evaltVal = app.AddSpecVF3.Value;
            loEvalLim = app.AddSpecVF3.Value;
            upEvalLim = app.AddSpecVF2.Value;
            nbEvalPts = app.AddSpecVF3.Value;

            varName = string(app.ICEvalVarDD.Value);
            app.system.setVarICEvalData(plotName,axisName,varName,varUnits,evaltVal,loEvalLim,upEvalLim,nbEvalPts);
        end

        % Value changed function: ICEvalVarDD
        function ICEvalVarDDValueChanged(app, event)
            varName = string(app.ICEvalVarDD.Value);
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            % ### FIXME: put in options for each of the DD
            % ### FIXME: also need to make sure that only the relevant
            % variables are being displayed
            % 1 - evaltVal, 2 - loEvalLim, 3 - upEvalLim, 4 - nbEvalPts, 5
            % - isDV, 6 - varIsIC, 7 - needDefineEvalt
            varData = app.system.getVarICEvalData(plotName,axisName,varName);
            % ### STARTHERE: need to fix this, units is getting messed up
            % UIcomps = ["evalUnitsVF","AddSpecVF1","AddSpecVF2","AddSpecVF3"];
            app.evalUnitsVF.Value = varData{1};
            if varData{end}
                app.AddSpecVF1.Value = varData{2};
                app.AddSpecVF2.Value = 0;
                app.AddSpecVF3.Value = 0;
            else
                app.AddSpecVF1.Value = varData{3};
                app.AddSpecVF2.Value = varData{4};
                app.AddSpecVF3.Value = varData{5};
            end
        
            % if var is DV
            if varData{6}
                % if need to define eval t
                if varData{end}
                    app.AddSpecL1.Text = "Evaluate @ Time (t) =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;
                    % ### FIXME: add time evaluation options
                    % set evaluation time, check if it is within the
                    % sys.model_time

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                end
            else
                % if var is IC
                if varData{7}
                    app.AddSpecL1.Text = "Lower Evaluation Limit =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;

                    app.AddSpecL2.Text = "Upper Evaluation Limit =";
                    app.AddSpecL2.Visible = true;
                    app.AddSpecVF2.Visible = true;
                    app.AddSpecVF2.Enable = true;

                    app.AddSpecL3.Text = "Number of Evaluation Points (#Pts) =";
                    app.AddSpecL3.Visible = true;
                    app.AddSpecVF3.Visible = true;
                    app.AddSpecVF3.Enable = true;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecVF2.Visible = false;
                    app.AddSpecVF2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecVF3.Visible = false;
                    app.AddSpecVF3.Enable = false;
                end
            end
        end

        % Value changed function: PlotTitleDD
        function PlotTitleDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            plot = app.system.getPlotByName(plotName);
            plotUI = plot.getAllPlotProps();
            axesUI = plot.getAllAxProps();
            app.updatePlotUI(plotUI);
%             app.PlotTitleF.Value = plotName;
%             app.NbAxesDD.Value = app.NbAxesDD.Items{length(axesUI)-1};
            app.updateAxesUI(axesUI);
        end

        % Button pushed function: ImportDataB
        function ImportBButtonPushed(app, event)
            [importedData,path,colNames] = app.system.getUserImportedData();
            app.ImportDataB.Enable = false;
            app.DataImportWindow = DataImportWindow(app,path,importedData,colNames,app.ImportDataB); %#ok<ADPROPLC> 
        end

        % Value changed function: RegCompParamDD
        function RegCompParamDDValueChanged(app, event)
            % ### FIXME: add functionality to take both helper functions
            % and env functions
            compName = app.RegCompParamDD.Value;
            % getting growth parameter data from Species
            params = app.system.getGrthParamsByCompName(compName);
            app.RegParamDD.Items = params(:,3);
        end

        % Button pushed function: RegParamAB, RegParamRB
        function RegParamUpdate(app, event)
            tag = event.Source.Tag;
            paramSym = app.RegParamDD.Value;
            compName = app.RegCompParamDD.Value;
            params = app.system.updateRegParamList(compName,paramSym,tag);
            app.RegParamT.Data = params;
            app.SaveRegParamDD.Items = params(:,1);
        end

        % Button pushed function: VarDataMatchB, VarDataUnmatchB
        function UpdateDVDataMatching(app, event)
            match = event.Source.Tag;
            sysVar = app.RegSysVarDD.Value;
            importVar = app.ImportedDataDD.Value;
            importVarNum = convertCharsToStrings(app.ImportedDataDD.Items) == convertCharsToStrings(importVar);
            matches = app.system.updateVarMatch(sysVar,importVar,importVarNum,match);
            app.RegVarDataT.Data = matches;
            matches_2 = matches(:,1);
            app.RegDVDD.Items = matches_2(~strcmp(matches_2,'Model Runtime'));
            regParamList = app.system.getRegParamList();
            if ~isempty(app.RegDVDD.Items) && ~isempty(regParamList)
                app.RegSpecsB.Enable = true;
                app.RunRegB.Enable = true;
            else
                app.RegSpecsB.Enable = false;
                app.RunRegB.Enable = false;
                app.RegAnalysisB.Enable = false;
            end
        end

        % Button pushed function: RegDVAB
        function RegDVPlotUpdate(app, event)
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNamesReg();
            DVIdx = strcmp(comps(:,1),varName);
            [plotRegData,plotImportData] = app.system.updateRegPlotDV(DVIdx);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,plotImportData(:,1),plotImportData(:,2),'ko');
            plot(app.RegPlot,linspace(plotRegData(1,1),plotRegData(end,1),100), ...
                spline(plotRegData(:,1),plotRegData(:,2), ...
                linspace(plotRegData(1,1),plotRegData(end,1),100)),'k-');
            hold(app.RegPlot,"off");
        end

        % Button pushed function: RegSpecsB
        function RegSpecsBButtonPushed(app, event)
            regSpecs = app.system.getRegSpecs();
            regParamList = app.system.getRegParamList();
            regParamList = regParamList(:,[1,4]);
            app.RegSpecsB.Enable = false;
            app.RegSpecsWindow = RegSpecsWindow(app,regSpecs,regParamList,app.RegSpecsB); %#ok<ADPROPLC> 
        end

        % Button pushed function: RunRegB
        function RunRegBPushed(app, event)
            % ### FIXME: for the starting point for the regression, the
            % first data point of the imported data should be the initial
            % condition for the regression, but since not every DV will
            % necessarily be imported, need to just recommend that each
            % imported data set start at time = 0

            regStats = app.system.compileRegression();
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNamesReg();
            DVIdx = strcmp(comps(:,1),varName);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,regStats.IVs,regStats.DVs(:,(app.RegDVDD.Items == varName)),'ko');
            plot(app.RegPlot,linspace(regStats.tRes(1),regStats.tRes(end),100), ...
                spline(regStats.tRes,regStats.yRes(:,DVIdx), ...
                linspace(regStats.tRes(1),regStats.tRes(end),100)),'k-');
            hold(app.RegPlot,"off");

            for k=1:1:length(regStats.beta), app.RegParamT.Data{k,5} = regStats.beta(k); end

            app.RegAnalysisB.Enable = true;
        end

        % Close request function: MAGMAUIFigure
        function MAGMAUIFigureCloseRequest(app, event)
            delete(app.RegSpecsWindow)
            delete(app.DataImportWindow)
            delete(app.RegAnalysisWindow)
            delete(app)
            
        end

        % Button pushed function: RegAnalysisB
        function RegAnalysisBPushed(app, event)
            % ### FIXME: need to code the nlparci and nlpredci functions,
            % give user options to customize the analysis
            % create popup window that can give these options to user
            % NRMSE should be done per DV
            app.RegAnalysisWindow = RegAnalysisWindow(app); %#ok<ADPROPLC>
        end

        % Callback function
        function ButtonPushed(app, event)
            disp(jsonencode(app.system));
        end

        % Button pushed function: HelperAB, MTHelperAB
        function HelperABButtonPushed(app, event)
            helperName = "Helper Function "+(length(app.HelperDD.Items)+1);
            app.HelperNameF.Value = helperName;
            app.HelperSymF.Value = "H_F_"+(length(app.HelperDD.Items)+1);
            app.HelperValF.Value = "1";
            app.HelperDD.Items = [app.HelperDD.Items,helperName];
            app.HelperDD.Value = app.HelperDD.Items{end};

            app.MTHelperNameF.Value = helperName;
            app.MTHelperSymF.Value = "H_F_"+(length(app.HelperDD.Items));
            app.MTHelperValF.Value = "1";
            app.MTHelperDD.Items = [app.HelperDD.Items,helperName];
            app.MTHelperDD.Value = app.HelperDD.Items{end};

            app.HelperAB.Enable = false;
            app.HelperRB.Enable = false;
            app.HelperDD.Enable = false;
            app.HelperUB.Enable = true;

            app.MTHelperAB.Enable = false;
            app.MTHelperRB.Enable = false;
            app.MTHelperDD.Enable = false;
            app.MTHelperUB.Enable = true;

            app.HelperSaveL.Text = "Not Saved";
            app.MTHelperSaveL.Text = "Not Saved";
        end

        % Button pushed function: HelperUB, MTHelperUB
        function HelperUBButtonPushed(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperNameF.Value;
                funcSym = app.HelperSymF.Value;
                funcVal = app.HelperValF.Value;
    
                nameEnabled = app.HelperNameF.Enable;
                symEnabled = app.HelperSymF.Enable;
                valEnabled = app.HelperValF.Enable;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperNameF.Value;
                funcSym = app.MTHelperSymF.Value;
                funcVal = app.MTHelperValF.Value;
    
                nameEnabled = app.MTHelperNameF.Enable;
                symEnabled = app.MTHelperSymF.Enable;
                valEnabled = app.MTHelperValF.Enable;
            end

            if nameEnabled && symEnabled && valEnabled
                app.system.addRmHelperFuncs(funcName,funcSym,funcVal,true);
            elseif nameEnabled && ~symEnabled && ~valEnabled
                app.system.updateHelperFuncs("name",funcName,funcSym,funcVal);
            elseif ~nameEnabled && symEnabled && ~valEnabled
                app.system.updateHelperFuncs("sym",funcName,funcSym,funcVal);
            elseif ~nameEnabled && ~symEnabled && valEnabled
                app.system.updateHelperFuncs("val",funcName,funcSym,funcVal);
            end

            app.HelperAB.Enable = true;
            app.HelperRB.Enable = true;
            app.HelperDD.Enable = true;
            app.HelperUB.Enable = false;
            app.HelperNameF.Enable = true;
            app.HelperSymF.Enable = true;
            app.HelperValF.Enable = true;

            app.MTHelperAB.Enable = true;
            app.MTHelperRB.Enable = true;
            app.MTHelperDD.Enable = true;
            app.MTHelperUB.Enable = false;
            app.MTHelperNameF.Enable = true;
            app.MTHelperSymF.Enable = true;
            app.MTHelperValF.Enable = true;

            app.HelperSaveL.Text = "Saved";
            app.MTHelperSaveL.Text = "Saved";

            % add helperFunc to sysVar table
            varNames = app.system.getModelVarNames(["specs","chems","envs","helpers","defaultParams","updateModel"]);
            app.SysVarT.Data = varNames;
            app.MTSysVarT.Data = varNames;

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Button pushed function: HelperRB, MTHelperRB
        function HelperRBButtonPushed(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperNameF.Value;
                funcSym = app.HelperSymF.Value;
                funcVal = app.HelperValF.Value;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperNameF.Value;
                funcSym = app.MTHelperSymF.Value;
                funcVal = app.MTHelperValF.Value;
            end
            app.system.addRmHelperFuncs(funcName,funcSym,funcVal,false);

            app.HelperDDValueChanged();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Value changed function: HelperDD, MTHelperDD
        function HelperDDValueChanged(app, event)
            if strcmp(event.Source.Tag,'GF')
                funcName = app.HelperDD.Value;
            elseif strcmp(event.Source.Tag,'MT')
                funcName = app.MTHelperDD.Value;
            end
            if isempty(funcName)
                funcName = app.HelperDD.Items{1};
            end
            app.HelperNameF.Value = funcName;
            app.MTHelperNameF.Value = funcName;
            [funcSym,funcVal] = app.system.getHelperFunc(funcName);
            app.HelperSymF.Value = funcSym;
            app.HelperValF.Value = funcVal;
            app.MTHelperSymF.Value = funcSym;
            app.MTHelperValF.Value = funcVal;

            % ### UPDATE: disable HelperRB if the helper selected is a mass
            % transfer equilibrium helper that shouldn't be removed
            

            app.HelperValFValueChanging();
        end

        % Value changing function: HelperValF, MTHelperValF
        function HelperValFValueChanging(app, event)
            funcVal = string(event.Value);

            if strcmp(event.Source.Tag,'GF')
                funcSym = string(app.HelperSymF.Value);
            elseif strcmp(event.Source.Tag,'MT')
                funcSym = string(app.MTHelperSymF.Value);
            end

            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperSymF.Enable = false;

                app.MTHelperAB.Enable = false;
                app.MTHelperDD.Enable = false;
                app.MTHelperRB.Enable = false;
                app.MTHelperAB.Enable = false;
                app.MTHelperNameF.Enable = false;
                app.MTHelperSymF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                app.MTHelperUB.Enable = true;
            end

            try
                funcVal = uni2latex(char(funcVal));
                app.HelperValF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                app.MTHelperValF.BackgroundColor = [1,1,1];
                app.MTHelperUB.Enable = true;
            catch
                % turns HelperSymF color red
                app.HelperValF.BackgroundColor = [1,0.7,0.7];
                app.HelperUB.Enable = false;
                app.MTHelperValF.BackgroundColor = [1,0.7,0.7];
                app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            app.MTHelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + funcVal + "$";
            
            app.HelperFuncL.Text = funcStr;
            app.MTHelperFuncL.Text = funcStr;
        end

        % Value changing function: HelperNameF, MTHelperNameF
        function HelperNameFValueChanging(app, event)
            funcName = string(event.Value);
            
            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperSymF.Enable = false;
                app.HelperValF.Enable = false;

                app.MTHelperAB.Enable = false;
                app.MTHelperDD.Enable = false;
                app.MTHelperRB.Enable = false;
                app.MTHelperAB.Enable = false;
                app.MTHelperSymF.Enable = false;
                app.MTHelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                app.MTHelperUB.Enable = true;
            end

            try
                funcName = uni2latex(char(funcName));
                app.HelperNameF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                app.MTHelperNameF.BackgroundColor = [1,1,1];
                app.MTHelperUB.Enable = true;
            catch
                % turns GovFuncVF color red
                app.HelperNameF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.HelperUB.Enable = false;
                app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % update the name of the function in SubFuncDD
            items = convertCharsToStrings(app.HelperDD.Items);
            items(items == app.HelperDD.Value) = funcName;
            app.HelperDD.Items = items;
            app.HelperDD.Value = funcName;
            app.MTHelperDD.Items = items;
            app.MTHelperDD.Value = funcName;

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            app.MTHelperSaveL.Text = "Not Saved";
        end

        % Value changing function: HelperSymF, MTHelperSymF
        function HelperSymFValueChanging(app, event)
            funcSym = string(event.Value);
            funcVal = string(app.HelperValF.Value);

            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperValF.Enable = false;
            
                app.MTHelperAB.Enable = false;
                app.MTHelperDD.Enable = false;
                app.MTHelperRB.Enable = false;
                app.MTHelperAB.Enable = false;
                app.MTHelperNameF.Enable = false;
                app.MTHelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
                app.MTHelperUB.Enable = true;
            end

            try
                funcSym = uni2latex(char(funcSym));
                app.HelperSymF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                app.MTHelperSymF.BackgroundColor = [1,1,1];
                app.MTHelperUB.Enable = true;
            catch
                % turns HelperSymF color red
                app.HelperSymF.BackgroundColor = [1,0.7,0.7];
                app.HelperUB.Enable = false;
                app.MTHelperSymF.BackgroundColor = [1,0.7,0.7];
                app.MTHelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
            app.MTHelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + uni2latex(char(funcVal)) + "$";
            
            app.HelperFuncL.Text = funcStr;
            app.MTHelperFuncL.Text = funcStr;
        end

        % Button pushed function: SaveRegParamB
        function SaveRegParamBButtonPushed(app, event)
            paramSym = app.SaveRegParamDD.Value;
            RegParamT_Idx = strcmp(app.RegParamT.Data(:,1),paramSym);
            compName = app.RegParamT.Data{RegParamT_Idx,2};
            newParamVal = app.RegParamT.Data{:,5};

            compNames = [app.system.getCompNamesByType("Biological Solute",true),app.system.getCompNamesByType("Chemical Solute",true)];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            param_idx = strcmp(params(:,3),paramSym);
            newParamUnit = params{param_idx,6};
            newParamName = params{param_idx,4};
            funcName = params{param_idx,2};

            if any(strcmp(compNames,compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            % updating the data in RegParamT
            app.RegParamT.Data{RegParamT_Idx,4} = app.RegParamT.Data{RegParamT_Idx,5};

            % updating the data in GrthParamT
            app.ParamT.Data = params;
        end

        % Button pushed function: SaveAllRegParamB
        function SaveAllRegParamBButtonPushed(app, event)
            paramSym = string(app.SaveRegParamDD.Items);
            newParamVal = [app.RegParamT.Data{:,5}];
            for k=1:1:length(paramSym)
                compName = app.RegParamT.Data{k,2};
    
                compNames = [app.system.getCompNamesByType("Biological Solute",true),app.system.getCompNamesByType("Chemical Solute",true)];
                envFuncNames = app.system.getEnvFuncNames();
                helperFuncNames = app.system.getHelperFuncNames();
                if any(strcmp(compNames,compName))
                    params = app.system.getGrthParamsByCompName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    return;
                end

                param_idx = strcmp(convertCharsToStrings(params(:,3)),paramSym(k));
                newParamUnit = params{param_idx,6};
                newParamName = params{param_idx,4};
                funcName = params{param_idx,2};
    
                if any(strcmp(compNames,compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByCompName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    break;
                end
    
                % updating the data in RegParamT
                app.RegParamT.Data{k,4} = app.RegParamT.Data{k,5};
            end

            % updating the data in GrthParamT
            app.ParamT.Data = params;
        end

        % Value changed function: VolCB
        function VolCBValueChanged(app, event)
            val = app.VolCB.Value;
            
            if val
                app.HConstVF.Enable = true;
                app.HConstUDD.Enable = true;
                app.HConst2L.Enable = true;
                app.HConst2VF.Enable = true;
                app.HConst2UDD.Enable = true;
                app.HConstL.Enable = true;
            else
                app.HConstVF.Enable = false;
                app.HConstUDD.Enable = false;
                app.HConst2L.Enable = false;
                app.HConst2VF.Enable = false;
                app.HConst2UDD.Enable = false;
                app.HConstL.Enable = false;
            end
        end

        % Value changed function: SorptionModelDD
        function SorptionModelDDValueChanged(app, event)
            modelName = app.SorptionModelDD.Value;
            solventName = app.ChemNameDD.Value;
            soluteName = app.CompSoluteDD.Value;
            soluteNum = app.system.getChemicalNumFromName(soluteName);
            solventNum = app.system.getChemicalNumFromName(solventName);
            if solventNum == 0
                disp("chemical not added yet")
                solventNum = length(app.system.chemicals) + 1;
            end

            switch modelName
                case 'Partition Coefficient'
                    app.Param1L.Text = string(['$K_{C_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}}=$']);
                    app.Param2L.Text = '';
                    app.Param3L.Text = '';
                    
                case 'Freundlich Adsorption'
                    app.Param1L.Text = ['$K_{C_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}}=$'];
                    app.Param2L.Text = ['$n_{',char(string(solventNum)),'_{',char(string(soluteNum)),'}}=$'];
                    app.Param3L.Text = '';

            end
        end

        % Value changed function: MTCompDD
        function MTCompDDValueChanged(app, event)
            compName = app.MTCompDD.Value;

            if isempty(compName), return; end

            % check which phases the component is in
            % is_vol
            % sorpFuncParams is empty?
            items = {'Liquid'};
            comp = app.system.getCompByName(compName);
            if comp.is_vol
                items{end+1} = 'Gas';
            end
            if ~isempty(comp.sorpFuncParams)
                for k=1:1:length(comp.sorpFuncParams)
                    items{end+1} = comp.sorpFuncParams{k}.solventName; %#ok<AGROW>
                end
            end
            
            % fill in PhaseADD and PhaseBDD
            app.MTPhaseADD.Items = items;
            app.MTPhaseADD.Value = items{1};
            app.MTPhaseBDD.Items = items;
            app.MTPhaseBDD.Value = items{2};
 
            % update MTGovFuncL, MTFuncVF, and MTPiecewiseT by calling
            % MTPhaseDDValueChanged
            app.MTPhaseDDValueChanged(struct('Source',struct('Tag','A')));
        end

        % Value changed function: CompSoluteDD
        function CompSoluteDDValueChanged(app, event)
            value = app.CompSoluteDD.Value;
            
        end

        % Value changed function: GasInFlowCB, GasOutFlowCB, LiqInFlowCB, 
        % ...and 1 other component
        function IOFlowUBButtonPushed(app, event)
            % set available types of streams
            strm_types = {};
            if app.LiqInFlowCB.Value, strm_types{end+1} = 'Liquid Flow In'; end
            if app.LiqOutFlowCB.Value, strm_types{end+1} = 'Liquid Flow Out'; end
            if app.GasInFlowCB.Value, strm_types{end+1} = 'Gas Flow In'; end
            if app.GasOutFlowCB.Value, strm_types{end+1} = 'Gas Flow Out'; end

            app.system.setStrmTypes(strm_types);

            app.StrmTypeDD.Items = strm_types;
            app.StrmTypeDDValueChanged();
            % ### UPDATE: need to update all DD.Items that depend on
            % whether there is an input/output
        end

        % Value changed function: StrmTypeDD
        function StrmTypeDDValueChanged(app, event)
            strmType = app.StrmTypeDD.Value;

            switch string(strmType)
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';
            end

            strms = app.system.getStreams(dir,phase);
            
            strmNames = cell(size(strms));
            for k=1:1:length(strmNames), strmNames{k} = strms{k}.name; end
            app.StrmNDD.Items = strmNames;
            app.StrmNDDValueChanged();
        end

        % Value changed function: StrmNDD
        function StrmNDDValueChanged(app, event)
            strmName = app.StrmNDD.Value;
            
            if ~isempty(strmName)    
                app.StrmT.Data = app.system.getStrmTData();
            end            
        end

        % Button pushed function: StrmAB
        function StrmABButtonPushed(app, event)
            newStrmName = app.NewStrmNF.Value;
            strmType = app.StrmTypeDD.Value;

            switch strmType
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';
            end

            app.system.addStream(newStrmName,dir,phase);

            % should only be called after 1st addition of stream of each
            % type
            app.system.updateIOFunc();

            strms = app.system.getStreams(dir,phase);
            strmNames = cell(size(strms));
            for k=1:1:length(strmNames), strmNames{k} = strms{k}.getName(); end
            app.StrmNDD.Items = strmNames;

            app.StrmT.Data = app.system.getStrmTData();

            app.system.compileModel();
        end

        % Button pushed function: StrmRB
        function StrmRBButtonPushed(app, event)
            strmName = app.StrmNDD.Value;
            strmType = app.StrmTypeDD.Value;

            switch strmType
                case 'Liquid Flow In'
                    dir = 'in';
                    phase = 'L';
                case 'Gas Flow In'
                    dir = 'in';
                    phase = 'G';
                case 'Liquid Flow Out'
                    dir = 'out';
                    phase = 'L';
                case 'Gas Flow Out'
                    dir = 'out';
                    phase = 'G';
            end

            app.system.removeStream(strmName,dir,phase);

            % should only be called after 1st addition of stream of each
            % type
            app.system.updateIOFunc(strm_types);

            app.StrmNDDValueChanged();

            app.system.compileModel();
        end

        % Value changed function: MTPhaseADD, MTPhaseBDD
        function MTPhaseDDValueChanged(app, event)
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;
            compName = app.MTCompDD.Value;
            tag = event.Source.Tag;

            % update the items for PhaseADD and PhaseBDD
            if strcmp(phaseA,phaseB)
                if strcmp(tag,'A')
                    % fill in PhaseADD and PhaseBDD
                    app.MTPhaseBDD.Value = app.currPhaseA;
                elseif strcmp(tag,'B')
                    app.MTPhaseADD.Value = app.currPhaseB;
                end
            end

            app.currPhaseA = app.MTPhaseADD.Value;
            app.currPhaseB = app.MTPhaseBDD.Value;

            % get current MT gov func
            currGovFuncRaw = app.system.getMTGovFunc(compName,app.currPhaseA,app.currPhaseB);
            compSym = app.system.getCompSymByName([compName,' (',app.currPhaseA,' Phase)']);
            funcStr = "$\frac{d"+compSym+"}{dt}="+uni2latex(currGovFuncRaw)+"$";
            app.MTGovFuncL.Text = funcStr;

            % get current Piecewise info
            
        end

        % Button pushed function: MTModelUB
        function MTModelUBButtonPushed(app, event)
            funcVal = app.MTGovFuncVF.Value;
            compName = app.MTCompDD.Value;
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;

            app.system.setMTModel(compName,funcVal,phaseA,phaseB);

            % ### UPDATE: enable piecewise editors
            app.MTPWVarDD.Enable = true;
            app.MTPWRB.Enable = true;
            app.MTPWAB.Enable = true;
            app.MTPWDD.Enable = true;
            app.MTLowLimVF.Enable = true;
            app.MTUpLimVF.Enable = true;
            app.MTLowLimOpDD.Enable = true;
            app.MTUpLimOpDD.Enable = true;
            app.MTElseValDD.Enable = true;

            app.MTSaveL.Text = "Saved";
            app.MTModelUB.Enable = false;

            % getting growth parameter data from Species
            params = app.system.getGrthParamsByCompName(compName);
            % updating the data in GrthParamT
            app.ParamT.Data = params;

            % ### UPDATE: automatically remove any piecewise expressions
            % that no longer exist in the governing function


            % updating the Param editors with CompParamDD callback
            app.CompParamDDValueChanged();
            % getting growth parameter data for regression
            app.RegCompParamDDValueChanged();

            % updates MSumL text
            app.updateSumL();

            % ### UPDATE/DEMO: separate compilation of parameters from
            % compilation of everything else
            app.system.compileModel();
        end

        % Button pushed function: MTFuncNewAB
        function MTFuncNewABButtonPushed(app, event)
            compName = app.MTCompDD.Value;
            
            currFuncVal = app.MTGovFuncL.Value;
            newExpressionName = app.MTFuncTypeDD.Value;

            % ### FIXME
            % gets new expression value from defaults
            newFunc = app.system.getDefaultFuncVal(compName,newExpressionName);

            % set governing func saved status to "Not Saved"
            app.MTSaveL.Text = "Not Saved";
            
            % add expression to GovFuncVF
            app.MTGovFuncVF.Value = [currFuncVal,newFunc];

            % disables piecewise editors
            app.MTPWVarDD.Enable = false;
            app.MTPWRB.Enable = false;
            app.MTPWAB.Enable = false;
            app.MTPWDD.Enable = false;
            app.MTLowLimVF.Enable = false;
            app.MTUpLimVF.Enable = false;
            app.MTLowLimOpDD.Enable = false;
            app.MTUpLimOpDD.Enable = false;
            app.MTElseValDD.Enable = false;

            % enable ModelUB
            app.MTModelUB.Enable = true;
        end

        % Button pushed function: MTPWAB
        function MTPWABButtonPushed(app, event)
            % check if the value is new or existing
            compName = app.MTCompDD.Value;
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;
            newExpr = app.MTPWDD.Value;
            lowLim = app.MTLowLimVF.Value;
            upLim = app.MTUpLimVF.Value;
            sysVar = app.MTPWVarDD.Value;
            elseVal = app.MTElseValDD.Value;
            lowLimOp = app.MTLowLimOpDD.Value;
            upLimOp = app.MTUpLimOpDD.Value;
            if contains(app.MTPWDD.Items,newExpr)
                pwTInfo = app.system.editNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,phaseA,phaseB);
            else
                app.MTPWDD.Items = [app.MTPWDD.Items,{newExpr}];
                pwTInfo = app.system.addNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,phaseA,phaseB);
            end

            % display the piecewise function info in the table
            app.MTPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByName(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.MTGovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Button pushed function: MTPWRB
        function MTPWRBButtonPushed(app, event)
            % check if the value exists
            % just break out from this function if the expression doesn't
            % exist. give the user a warning or note it in documentation
            compName = app.MTCompDD.Value;
            phaseA = app.MTPhaseADD.Value;
            phaseB = app.MTPhaseBDD.Value;
            newExpr = app.MTPWDD.Value;
            lowLim = app.MTLowLimVF.Value;
            upLim = app.MTUpLimVF.Value;
            sysVar = app.MTPWVarDD.Value;
            elseVal = app.MTElseValDD.Value;
            lowLimOp = app.MTLowLimOpDD.Value;
            upLimOp = app.MTUpLimOpDD.Value;
            if contains(app.MTPWDD.Items,newExpr)
                app.MTPWDD.Items = app.MTPWDD.Items([app.MTPWDD.Items{:}] ~= newExpr);
                pwTInfo = app.system.rmPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,phaseA,phaseB);
            else
                disp("piecewise expression doesn't exist, line 2423")
                return;
            end

            % edit the piecewise function info in the table
            app.MTPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByNamec(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.MTGovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Value changing function: GovFuncVF
        function GovFuncVFValueChanging(app, event)
            funcVal = event.Value;
            
            if app.SaveL.Text == "Saved"
                % disables relevant editors
                app.PiecewiseLimsP.Enable = false;
    
                % enable ModelUB
                app.ModelUB.Enable = true;
            end

            try
                newFunc = uni2latex(char(funcVal));
                app.GovFuncVF.BackgroundColor = [1,1,1];
                app.ModelUB.Enable = true;
            catch
                % turns GovFuncVF color red
                app.GovFuncVF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.ModelUB.Enable = false;
                disp("err")
                return;
            end
            
            compName = app.InteractCompDD.Value;
            compSym = app.system.getCompSymByName(compName);

            % set governing func saved status to "Not Saved"
            app.SaveL.Text = "Not Saved";

            funcStr = "$\frac{d"+compSym+"}{dt}="+newFunc;

            app.GovFuncL.Text = funcStr;            
        end

        % Button pushed function: FuncNewAB
        function FuncNewABButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            
            currFuncVal = app.GovFuncVF.Value;
            newExpressionName = app.FuncTypeDD.Value;

            % ### FIXME
            % gets new expression value from defaults
            newFunc = app.system.getDefaultFuncVal(compName,newExpressionName);

            % set governing func saved status to "Not Saved"
            app.SaveL.Text = "Not Saved";
            
            % add expression to GovFuncVF
            app.GovFuncVF.Value = [currFuncVal,newFunc];

            % disables relevant editors
            app.PiecewiseLimsP.Enable = false;

            % enable ModelUB
            app.ModelUB.Enable = true;
        end

        % Button pushed function: PiecewiseAB
        function PiecewiseABButtonPushed(app, event)
            % check if the value is new or existing
            compName = app.InteractCompDD.Value;
            newExpr = app.PiecewiseDD.Value;
            lowLim = app.LowLimVF.Value;
            upLim = app.UpLimVF.Value;
            sysVar = app.PiecewiseVarDD.Value;
            elseVal = app.ElseValDD.Value;
            lowLimOp = app.LowLimOpDD.Value;
            upLimOp = app.UpLimOpDD.Value;
            if contains(app.PiecewiseDD.Items,newExpr)
                pwTInfo = app.system.editNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            else
                app.PiecewiseDD.Items = [app.PiecewiseDD.Items,{newExpr}];
                pwTInfo = app.system.addNewPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            end

            % display the piecewise function info in the table
            app.GovFuncPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByName(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Button pushed function: PiecewiseRB
        function PiecewiseRBButtonPushed(app, event)
            % check if the value exists
            % just break out from this function if the expression doesn't
            % exist. give the user a warning or note it in documentation
            compName = app.InteractCompDD.Value;
            newExpr = app.PiecewiseDD.Value;
            lowLim = app.LowLimVF.Value;
            upLim = app.UpLimVF.Value;
            sysVar = app.PiecewiseVarDD.Value;
            elseVal = app.ElseValDD.Value;
            if contains(app.PiecewiseDD.Items,newExpr)
                app.PiecewiseDD.Items = app.PiecewiseDD.Items([app.PiecewiseDD.Items{:}] ~= newExpr);
                pwTInfo = app.system.rmPWExpr(compName,newExpr,lowLim,upLim,sysVar,elseVal,lowLimOp,upLimOp,'Main','');
            else
                disp("piecewise expression doesn't exist, line 2423")
                return;
            end

            % edit the piecewise function info in the table
            app.GovFuncPiecewiseT.Data = pwTInfo;

            % edit the displayed govfunc to give piecewise functions
            compSym = app.system.getCompSymByNamec(compName);
            existFunc = app.system.getGovFuncByCompName(compName);
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFunc+"$";
        end

        % Value changed function: PlotTitleF
        function PlotTitleFValueChanged(app, event)
            % ### FIXME: add functionality to update whether selected
            % variable is a set of IC
            % add functionality to update the options for the ranges of max
            % and min

            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
                app.AxVarUBButtonPushed(struct('Source',app.YAxVarAB));
            end
            % ### FIXME: add code to disable Z-axis if only 2 axes
            % specified
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
            items = convertCharsToStrings(app.PlotTitleDD.Items);
            items(items == app.PlotTitleDD.Value) = title;
            app.PlotTitleDD.Items = ~strcmp(items,"");
            app.PlotTitleDD.Value = title;

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};            
        end

        % Value changed function: XAxTitleF, YAxTitleF, ZAxTitleF
        function AxTitleFValueChanged(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            newTitle = string(app.(dir+"AxTitleF").Value);
            axesUI = app.system.updateAxTitle(plotName,dir,newTitle);
            axesNames = cell(1,length(axesUI));
            for k=1:1:length(axesNames)
                axesNames{k} = char(axesUI{k}{2});
            end
            app.ICEvalAxisDD.Items = axesNames;
            app.ICEvalAxisDDValueChanged();              
        end

        % Value changed function: NbAxesDD
        function NbAxesDDValueChanged(app, event)
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
            end
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;

            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: CreateNewModelB
        function CreateNewModelBButtonPushed(app, event)
            % should re-instantiate the ODESys class
            app.system = ODESys();

            app.reinstantiateODESysUI();
        end

        % Window button down function: MAGMAUIFigure
        function MAGMAUIFigureWindowButtonDown(app, event)
            % set the Home Tab "Saved" label to "Not Saved"
            app.ModelSavedL.Text = "Model NOT Saved!";
        end

        % Button pushed function: ModelSaveB
        function ModelSaveBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            if strcmp(app.system.save_type,"file")
                try
                    fid = fopen(app.system.save_file_name,'w');
                    fprintf(fid,'%s',jsonencode(app.system));
                    fclose(fid);
                catch err
                    disp(err)
                end
            elseif strcmp(app.system.save_type,"database")

            end
        end

        % Button pushed function: ModelSave2FileB
        function ModelSave2FileBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            [file,path] = uiputfile();
            try
                app.system.save_file_name = [path,file];
                fid = fopen([path,file],'w');
                fprintf(fid,'%s',jsonencode(app.system));
                fclose(fid);
            catch err
                disp(err)
            end
        end

        % Button pushed function: ModelSave2DBB
        function ModelSave2DBBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";
        end

        % Button pushed function: ModelFileLoadB
        function ModelFileLoadBButtonPushed(app, event)
            app.ModelSavedL.Text = "Model Saved!";

            [file,path] = uiputfile();
            try
                app.system = jsondecode(fileread([path,file]));
    
                app.reinstantiateODESysUI();
            catch err
                disp(err)
            end
        end

        % Value changed function: ModelDescHTA
        function ModelDescHTAValueChanged(app, event)
            value = app.ModelDescHTA.Value;
            app.system.save_model_desc = value;
        end

        % Value changed function: ModelNameTA
        function ModelNameTAValueChanged(app, event)
            value = app.ModelNameTA.Value;
            app.system.save_model_name = value;
        end

        % Value changed function: RefHTA
        function RefHTAValueChanged(app, event)
            value = app.RefHTA.Value;
            app.system.save_model_ref_str = value;
        end

        % Button pushed function: SubplotUB
        function SubplotUBButtonPushed(app, event)
            rows = ap.SubplotRVF.Value;
            cols = app.SubplotCVF.Value;

            app.system.subplot_row_ct = rows;
            app.system.subplot_col_ct = cols;

            app.SubplotGroupDDValueChanged();
        end

        % Value changed function: SubplotGroupDD
        function SubplotGroupDDValueChanged(app, event)
            subplotGroup = app.SubplotGroupDD.Value;
            subplotSlot = app.PlotPosDD.Value;
            tot_ct = app.system.subplot_row_ct(subplotGroup)*app.system.subplot_col_ct*(subplotGroup);
            app.PlotPosDD.Items = 1:1:tot_ct;

            plotName = app.PlotTitleDD.Value;
            app.system.updatePlotGroupSlot(plotName,subplotGroup,subplotSlot);
        end

        % Button pushed function: SubplotAB
        function SubplotABPushed(app, event)
            % add new number to SubplotDD
            app.SubplotDD.Items{end+1} = length(app.SubplotDD.Items) + 1;

            % update subplot_ct in ODESys
            app.system.subplot_ct = app.system.subplot_ct + 1;
            app.system.subplot_row_ct(end+1) = 1;
            app.system.subplot_col_ct(end+1) = 1;

            % update subplot UI on Plots Tab
            app.PlotTitleDDValueChanged();
            app.SubplotGroupDDValueChanged();
        end

        % Button pushed function: SubplotRB
        function SubplotRBButtonPushed(app, event)
            % update subplot props in Plot class
            % if the subplot that the Plot was on was deleted, reset all
            % subplot props in that Plot to 0
            app.system.updateSubplotPropsinPlot(length(app.SubplotDD.Items));
            
            % remove last number from SubplotDD
            app.SubplotDD.Items(end) = [];

            % update subplot_ct in ODESys
            app.system.subplot_ct = app.system.subplot_ct - 1;
            app.system.subplot_row_ct(end) = [];
            app.system.subplot_col_ct(end) = [];

            % update subplot UI on Plots Tab
            app.PlotTitleDDValueChanged();
            app.SubplotGroupDDValueChanged();
        end

        % Value changed function: SubplotDD
        function SubplotDDValueChanged(app, event)
            numSubplots = app.SubplotDD.Value;
            
            [app.SubplotRVF.Value,app.SubplotCVF.Value] = app.system.getSubplotRowAndCol(numSubplots);
        end

        % Value changed function: PlotPosDD
        function PlotPosDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            subplotGroup = app.SubplotGroupDD.Value;
            subplotSlot = app.PlotPosDD.Value;
            app.system.updatePlotGroupSlot(plotName,subplotGroup,subplotSlot);            
        end

        % Button pushed function: DataExportDirB
        function DataExportDirBButtonPushed(app, event)
            [fileName,dirName] = uiputfile;
            app.system.data_export_dir = [dirName,fileName];
            app.PlotDirL.Text = "Directory: "+[dirName,fileName];
        end

        % Button pushed function: DataExportB
        function DataExportBButtonPushed(app, event)
            % run the model and export data
            app.system.runModelAndExportData();

            % loading screen
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MAGMAUIFigure = uifigure('Visible', 'off');
            app.MAGMAUIFigure.Name = 'MAGMA';
            app.MAGMAUIFigure.CloseRequestFcn = createCallbackFcn(app, @MAGMAUIFigureCloseRequest, true);
            app.MAGMAUIFigure.WindowButtonDownFcn = createCallbackFcn(app, @MAGMAUIFigureWindowButtonDown, true);
            app.TabGroup = uitabgroup(app.MAGMAUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HomeTabGL = uigridlayout(app.HomeTab);
            app.ReferencesLabel = uilabel(app.HomeTabGL);
            app.ReferencesLabel.Text = 'Reference(s): ';
            app.RefHTA = uitextarea(app.HomeTabGL);
            app.RefHTA.ValueChangedFcn = createCallbackFcn(app, @RefHTAValueChanged, true);
            app.ModelDescriptionLabel = uilabel(app.HomeTabGL);
            app.ModelDescriptionLabel.Text = 'Model Description: ';
            app.ModelDescHTA = uitextarea(app.HomeTabGL);
            app.ModelDescHTA.ValueChangedFcn = createCallbackFcn(app, @ModelDescHTAValueChanged, true);
            app.ModelNameLabel = uilabel(app.HomeTabGL);
            app.ModelNameLabel.Text = 'Model Name: ';
            app.ModelNameTA = uitextarea(app.HomeTabGL);
            app.ModelNameTA.ValueChangedFcn = createCallbackFcn(app, @ModelNameTAValueChanged, true);
            app.ModelSave2FileB = uibutton(app.HomeTabGL, 'push');
            app.ModelSave2FileB.ButtonPushedFcn = createCallbackFcn(app, @ModelSave2FileBButtonPushed, true);
            app.ModelSave2FileB.Text = 'Save Model to File';
            app.ModelSave2DBB = uibutton(app.HomeTabGL, 'push');
            app.ModelSave2DBB.ButtonPushedFcn = createCallbackFcn(app, @ModelSave2DBBButtonPushed, true);
            app.ModelSave2DBB.Text = 'Save Model to Database';
            app.CreateNewModelB = uibutton(app.HomeTabGL, 'push');
            app.CreateNewModelB.ButtonPushedFcn = createCallbackFcn(app, @CreateNewModelBButtonPushed, true);
            app.CreateNewModelB.Text = 'Create New Model';
            app.ModelFileLoadB = uibutton(app.HomeTabGL, 'push');
            app.ModelFileLoadB.ButtonPushedFcn = createCallbackFcn(app, @ModelFileLoadBButtonPushed, true);
            app.ModelFileLoadB.Text = 'Load Model From File';
            app.ModelSearchTree = uitree(app.HomeTabGL);
            app.ModelSearchVF = uieditfield(app.HomeTabGL, 'text');
            app.ModelSearchB = uibutton(app.HomeTabGL, 'push');
            app.ModelSearchB.Text = 'Search';
            app.ModelDBLoadB = uibutton(app.HomeTabGL, 'push');
            app.ModelDBLoadB.Text = 'Load Model From Database';
            app.SearchByLabel = uilabel(app.HomeTabGL);
            app.SearchByLabel.Text = 'Search By: ';
            app.ModelSearchByDD = uidropdown(app.HomeTabGL);
            app.ModelSearchByDD.Items = {'Model Name', 'Model Description', 'Biological Solutes', 'Chemical Species', 'References'};
            app.ModelSearchByDD.Value = 'Model Name';
            app.ModelSaveB = uibutton(app.HomeTabGL, 'push');
            app.ModelSaveB.ButtonPushedFcn = createCallbackFcn(app, @ModelSaveBButtonPushed, true);
            app.ModelSaveB.Text = 'Save';
            app.ModelSavedL = uilabel(app.HomeTabGL);
            app.ModelSavedL.Text = 'Model Saved!';
            app.ModelBioChemT = uitable(app.HomeTabGL);
            app.ModelBioChemT.ColumnName = {'Biological Solutes'; 'Chemical Species'};
            app.ModelBioChemT.RowName = {};
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.GridLayout12 = uigridlayout(app.EnvironmentTab);
            app.EnvironParamFuncPlot = uiaxes(app.GridLayout12);
            zlabel(app.EnvironParamFuncPlot, 'Z')
            app.EnvironT = uitable(app.GridLayout12);
            app.EnvironT.ColumnName = {'Parameter'; 'Value'; 'Unit'};
            app.EnvironT.RowName = {};
            app.GenParamF = uieditfield(app.GridLayout12, 'text');
            app.GenParamUnitDD = uidropdown(app.GridLayout12);
            app.GenParamUnitDD.Items = {'hr', 'min', 'sec', 'day'};
            app.GenParamUnitDD.Editable = 'on';
            app.GenParamUnitDD.Value = 'hr';
            app.GenParamAB = uibutton(app.GridLayout12, 'push');
            app.GenParamAB.ButtonPushedFcn = createCallbackFcn(app, @GenParamABButtonPushed, true);
            app.GenParamAB.Text = 'Update';
            app.genparamddLabel = uilabel(app.GridLayout12);
            app.genparamddLabel.Text = 'Parameter';
            app.GenParamDD = uidropdown(app.GridLayout12);
            app.GenParamDD.Items = {'Model Runtime (t)', 'Initial Temperature (T_0)', 'Initial Pressure (P_0)', 'Initial Culture Volume (V_0)', 'Initial pH (pH_0)'};
            app.GenParamDD.Editable = 'on';
            app.GenParamDD.ValueChangedFcn = createCallbackFcn(app, @GenParamDDValueChanged, true);
            app.GenParamDD.Value = 'Model Runtime (t)';
            app.EnvironParamFuncL = uilabel(app.GridLayout12);
            app.EnvironParamFuncL.Interpreter = 'latex';
            app.EnvironParamFuncL.Text = '$t =$';
            app.EnvironmentDesignPanel = uipanel(app.GridLayout12);
            app.EnvironmentDesignPanel.Title = 'Environment Design';
            app.GridLayout36 = uigridlayout(app.EnvironmentDesignPanel);
            app.ReactorImg = uiimage(app.GridLayout36);
            app.ReactorImg.ImageSource = fullfile(pathToMLAPP, 'Cylinder.png');
            app.ReactorTypeDropDownLabel = uilabel(app.GridLayout36);
            app.ReactorTypeDropDownLabel.Text = 'Reactor Type';
            app.ReactorTypeDD = uidropdown(app.GridLayout36);
            app.ReactorTypeDD.Items = {'Vertical Continuous Stirred Tank', 'Horizontal Plug Flow Reactor', 'Flat Panel', 'Raceway Pond', 'Shaking Flask', 'Benchtop Bioreactor', 'Eutrophic Lake System'};
            app.ReactorTypeDD.Editable = 'on';
            app.ReactorTypeDD.Value = 'Vertical Continuous Stirred Tank';
            app.ReactParam1VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam1VF.Value = 400;
            app.ReactParamUnit1DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit1DD.Items = {'rpm', 'rad/s'};
            app.ReactParamUnit1DD.Editable = 'on';
            app.ReactParamUnit1DD.Value = 'rpm';
            app.LightingConfigurationDropDownLabel = uilabel(app.GridLayout36);
            app.LightingConfigurationDropDownLabel.Text = 'Lighting Configuration';
            app.ReactParam5DD = uidropdown(app.GridLayout36);
            app.ReactParam5DD.Items = {'Internal', 'External', 'None'};
            app.ReactParam5DD.Editable = 'on';
            app.ReactParam5DD.Value = 'Internal';
            app.ReactParam6VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam7VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParamUnit7DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit7DD.Items = {'m', 'cm', 'mm'};
            app.ReactParamUnit7DD.Editable = 'on';
            app.ReactParamUnit7DD.Value = 'm';
            app.ReactParam2VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam2VF.Value = 3;
            app.ReactParamUnit2DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit2DD.Items = {'m', 'cm', 'mm'};
            app.ReactParamUnit2DD.Editable = 'on';
            app.ReactParamUnit2DD.Value = 'm';
            app.ReactParamUnit3DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit3DD.Items = {'m', 'cm', 'mm'};
            app.ReactParamUnit3DD.Editable = 'on';
            app.ReactParamUnit3DD.Value = 'm';
            app.ReactParam3VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam3VF.Value = 1;
            app.ReactParam4VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParam4VF.Enable = 'off';
            app.ReactParamUnit4DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit4DD.Items = {''};
            app.ReactParamUnit4DD.Editable = 'on';
            app.ReactParamUnit4DD.Enable = 'off';
            app.ReactParamUnit4DD.Value = '';
            app.ReactParamUnit6DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit6DD.Items = {''};
            app.ReactParamUnit6DD.Editable = 'on';
            app.ReactParamUnit6DD.Value = '';
            app.HelpBut1 = uibutton(app.GridLayout36, 'push');
            app.HelpBut1.Tag = '1';
            app.HelpBut1.Text = '?';
            app.Label_12 = uilabel(app.GridLayout36);
            app.Label_12.Text = '';
            app.TankInnerDiameterLabel = uilabel(app.GridLayout36);
            app.TankInnerDiameterLabel.Text = 'Tank Inner Diameter';
            app.TankHeightLabel = uilabel(app.GridLayout36);
            app.TankHeightLabel.Interpreter = 'html';
            app.TankHeightLabel.Text = 'Tank Height';
            app.LightSourcesLabel = uilabel(app.GridLayout36);
            app.LightSourcesLabel.Text = '# Light Sources';
            app.LightSourceDiameterLabel = uilabel(app.GridLayout36);
            app.LightSourceDiameterLabel.Text = 'Light Source Diameter';
            app.LightSourceCircleRadiusLabel = uilabel(app.GridLayout36);
            app.LightSourceCircleRadiusLabel.Text = 'Light Source Circle Radius';
            app.ReactParam8VF = uieditfield(app.GridLayout36, 'numeric');
            app.ReactParamUnit8DD = uidropdown(app.GridLayout36);
            app.ReactParamUnit8DD.Items = {'m', 'cm', 'mm'};
            app.ReactParamUnit8DD.Editable = 'on';
            app.ReactParamUnit8DD.Value = 'm';
            app.HelpBut2 = uibutton(app.GridLayout36, 'push');
            app.HelpBut2.Tag = '2';
            app.HelpBut2.Text = '?';
            app.HelpBut3 = uibutton(app.GridLayout36, 'push');
            app.HelpBut3.Tag = '3';
            app.HelpBut3.Text = '?';
            app.HelpBut4 = uibutton(app.GridLayout36, 'push');
            app.HelpBut4.Tag = '4';
            app.HelpBut4.Text = '?';
            app.HelpBut5 = uibutton(app.GridLayout36, 'push');
            app.HelpBut5.Tag = '5';
            app.HelpBut5.Text = '?';
            app.HelpBut6 = uibutton(app.GridLayout36, 'push');
            app.HelpBut6.Tag = '6';
            app.HelpBut6.Text = '?';
            app.HelpBut7 = uibutton(app.GridLayout36, 'push');
            app.HelpBut7.Tag = '7';
            app.HelpBut7.Text = '?';
            app.HelpBut8 = uibutton(app.GridLayout36, 'push');
            app.HelpBut8.Tag = '8';
            app.HelpBut8.Text = '?';
            app.HelpBut9 = uibutton(app.GridLayout36, 'push');
            app.HelpBut9.Tag = '9';
            app.HelpBut9.Text = '?';
            app.AgSpdL = uilabel(app.GridLayout36);
            app.AgSpdL.Text = 'Agitation Speed';
            app.EnvGeomUB = uibutton(app.GridLayout36, 'push');
            app.EnvGeomUB.ButtonPushedFcn = createCallbackFcn(app, @GenParamABButtonPushed, true);
            app.EnvGeomUB.Text = 'Update';
            app.DropDown_7 = uidropdown(app.GridLayout36);
            app.DropDown_7.Items = {''};
            app.DropDown_7.Editable = 'on';
            app.DropDown_7.Enable = 'off';
            app.DropDown_7.Value = '';
            app.EditField_9 = uieditfield(app.GridLayout36, 'numeric');
            app.EditField_9.Enable = 'off';
            app.IncidentLightFuncVF = uieditfield(app.GridLayout36, 'text');
            app.IncidentLightFuncVF.Placeholder = '100';
            app.IncidentLightFuncVF.Value = '100';
            app.IncidentLightFuncL = uilabel(app.GridLayout36);
            app.IncidentLightFuncL.Interpreter = 'latex';
            app.IncidentLightFuncL.Text = '$I_0(t)=$';
            app.Button_3 = uibutton(app.GridLayout36, 'push');
            app.Button_3.Text = '?';
            app.SpecTab = uitab(app.TabGroup);
            app.SpecTab.Title = 'Species';
            app.SpecEnvironGrid = uigridlayout(app.SpecTab);
            app.SpeciesNameLabel = uilabel(app.SpecEnvironGrid);
            app.SpeciesNameLabel.Text = 'Biological Species';
            app.SpecNameDD = uidropdown(app.SpecEnvironGrid);
            app.SpecNameDD.Items = {''};
            app.SpecNameDD.Editable = 'on';
            app.SpecNameDD.Value = '';
            app.SpecAB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecAB.ButtonPushedFcn = createCallbackFcn(app, @SpecABPushed, true);
            app.SpecAB.Text = 'Add/Update';
            app.SpecT = uitable(app.SpecEnvironGrid);
            app.SpecT.ColumnName = {'Name'; 'Initial Concentration'; 'Unit'};
            app.SpecT.RowName = {};
            app.SpecT.ColumnSortable = [true false false];
            app.SpecRB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecRB.ButtonPushedFcn = createCallbackFcn(app, @SpecRBButtonPushed, true);
            app.SpecRB.Text = 'Remove';
            app.InitialConcentrationEditField_2Label = uilabel(app.SpecEnvironGrid);
            app.InitialConcentrationEditField_2Label.Text = 'Initial Concentration';
            app.SpecInitConcUnitDD = uidropdown(app.SpecEnvironGrid);
            app.SpecInitConcUnitDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.SpecInitConcUnitDD.Value = 'g/L';
            app.ChemAB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemAB.ButtonPushedFcn = createCallbackFcn(app, @ChemABPushed, true);
            app.ChemAB.Text = 'Add/Update';
            app.ChemicalNameLabel = uilabel(app.SpecEnvironGrid);
            app.ChemicalNameLabel.Text = 'Chemical Species';
            app.ChemNameDD = uidropdown(app.SpecEnvironGrid);
            app.ChemNameDD.Items = {''};
            app.ChemNameDD.Editable = 'on';
            app.ChemNameDD.ValueChangedFcn = createCallbackFcn(app, @ChemNameDDValueChanged, true);
            app.ChemNameDD.Value = '';
            app.ChemInitConcL = uilabel(app.SpecEnvironGrid);
            app.ChemInitConcL.Text = 'Initial Concentration';
            app.ChemInitConcUnitDD = uidropdown(app.SpecEnvironGrid);
            app.ChemInitConcUnitDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.ChemInitConcUnitDD.Value = 'g/L';
            app.ChemT = uitable(app.SpecEnvironGrid);
            app.ChemT.ColumnName = {'Chemical Name'; 'Type'; 'Volatile'; 'Initial State'; 'Unit'; 'MW'; 'H_0'; 'Unit'; 'A'; 'Unit'};
            app.ChemT.RowName = {};
            app.ChemRB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemRB.ButtonPushedFcn = createCallbackFcn(app, @ChemRBButtonPushed, true);
            app.ChemRB.Text = 'Remove';
            app.VolCB = uicheckbox(app.SpecEnvironGrid);
            app.VolCB.ValueChangedFcn = createCallbackFcn(app, @VolCBValueChanged, true);
            app.VolCB.Text = 'Volatile';
            app.HConstVF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConstVF.Enable = 'off';
            app.HConstUDD = uidropdown(app.SpecEnvironGrid);
            app.HConstUDD.Items = {'kPa*m^3/kg', 'kPa*m^3/mol'};
            app.HConstUDD.Enable = 'off';
            app.HConstUDD.Value = 'kPa*m^3/kg';
            app.CompSoluteDD = uidropdown(app.SpecEnvironGrid);
            app.CompSoluteDD.Items = {''};
            app.CompSoluteDD.Editable = 'on';
            app.CompSoluteDD.ValueChangedFcn = createCallbackFcn(app, @CompSoluteDDValueChanged, true);
            app.CompSoluteDD.Enable = 'off';
            app.CompSoluteDD.Value = '';
            app.SorptionT = uitable(app.SpecEnvironGrid);
            app.SorptionT.ColumnName = {'Compatible Solutes'; 'Sorption Model'; 'Parameters'};
            app.SorptionT.RowName = {};
            app.SorptionT.Enable = 'off';
            app.SorptionModelUB = uibutton(app.SpecEnvironGrid, 'push');
            app.SorptionModelUB.Enable = 'off';
            app.SorptionModelUB.Text = 'Add/Update';
            app.SorptionModelRB = uibutton(app.SpecEnvironGrid, 'push');
            app.SorptionModelRB.Enable = 'off';
            app.SorptionModelRB.Text = 'Remove';
            app.SorptionModelDD = uidropdown(app.SpecEnvironGrid);
            app.SorptionModelDD.Items = {'Partition Coefficient', 'Freundlich Adsorption'};
            app.SorptionModelDD.Editable = 'on';
            app.SorptionModelDD.ValueChangedFcn = createCallbackFcn(app, @SorptionModelDDValueChanged, true);
            app.SorptionModelDD.Enable = 'off';
            app.SorptionModelDD.Value = 'Partition Coefficient';
            app.SorpParam1VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam1VF.Enable = 'off';
            app.SorpParam2VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam2VF.Enable = 'off';
            app.SorpParam3VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SorpParam3VF.Enable = 'off';
            app.HConst2UDD = uidropdown(app.SpecEnvironGrid);
            app.HConst2UDD.Items = {'K', 'C', 'F'};
            app.HConst2UDD.Enable = 'off';
            app.HConst2UDD.Value = 'K';
            app.HConst2VF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConst2VF.Enable = 'off';
            app.HConst2L = uilabel(app.SpecEnvironGrid);
            app.HConst2L.Enable = 'off';
            app.HConst2L.Interpreter = 'latex';
            app.HConst2L.Text = '$A=$';
            app.HConstHB = uibutton(app.SpecEnvironGrid, 'push');
            app.HConstHB.Text = '?';
            app.HConst2HB = uibutton(app.SpecEnvironGrid, 'push');
            app.HConst2HB.Text = '?';
            app.Param1L = uilabel(app.SpecEnvironGrid);
            app.Param1L.Enable = 'off';
            app.Param1L.Interpreter = 'latex';
            app.Param1L.Text = '$K_C=$';
            app.Param2L = uilabel(app.SpecEnvironGrid);
            app.Param2L.Enable = 'off';
            app.Param2L.Interpreter = 'latex';
            app.Param2L.Text = '';
            app.Param3L = uilabel(app.SpecEnvironGrid);
            app.Param3L.Enable = 'off';
            app.Param3L.Interpreter = 'latex';
            app.Param3L.Text = '';
            app.Button_4 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_4.Text = '?';
            app.Button_5 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_5.Text = '?';
            app.Button_6 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_6.Text = '?';
            app.Button_7 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_7.Text = '?';
            app.Button_8 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_8.Text = '?';
            app.ChemInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SpecInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.HConstL = uilabel(app.SpecEnvironGrid);
            app.HConstL.Enable = 'off';
            app.HConstL.Interpreter = 'latex';
            app.HConstL.Text = '$H_0$';
            app.CompSolutesL = uilabel(app.SpecEnvironGrid);
            app.CompSolutesL.Enable = 'off';
            app.CompSolutesL.Text = 'Compatible Solutes:';
            app.SorpEqML = uilabel(app.SpecEnvironGrid);
            app.SorpEqML.Enable = 'off';
            app.SorpEqML.Text = 'Sorption Equilibrium Model:';
            app.TypeDropDownLabel = uilabel(app.SpecEnvironGrid);
            app.TypeDropDownLabel.Text = 'Type';
            app.ChemTypeDD = uidropdown(app.SpecEnvironGrid);
            app.ChemTypeDD.Items = {'Chemical Solute', 'Suspended Solid Sorbent'};
            app.ChemTypeDD.Editable = 'on';
            app.ChemTypeDD.Value = 'Chemical Solute';
            app.MolecularWeightEditFieldLabel = uilabel(app.SpecEnvironGrid);
            app.MolecularWeightEditFieldLabel.Text = 'Molecular Weight';
            app.MWVF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.Button_9 = uibutton(app.SpecEnvironGrid, 'push');
            app.Button_9.Text = '?';
            app.MassTransferTab = uitab(app.TabGroup);
            app.MassTransferTab.Title = 'Mass Transfer';
            app.GridLayout27 = uigridlayout(app.MassTransferTab);
            app.InputandOutputStreamsPanel = uipanel(app.GridLayout27);
            app.InputandOutputStreamsPanel.Title = 'Input and Output Streams';
            app.GridLayout34 = uigridlayout(app.InputandOutputStreamsPanel);
            app.StrmT = uitable(app.GridLayout34);
            app.StrmT.ColumnName = {'Stream'; 'Symbol'; 'Phase'; 'Direction'};
            app.StrmT.RowName = {};
            app.StrmTypeDD = uidropdown(app.GridLayout34);
            app.StrmTypeDD.Items = {''};
            app.StrmTypeDD.Editable = 'on';
            app.StrmTypeDD.ValueChangedFcn = createCallbackFcn(app, @StrmTypeDDValueChanged, true);
            app.StrmTypeDD.Value = '';
            app.StreamNameDropDownLabel = uilabel(app.GridLayout34);
            app.StreamNameDropDownLabel.Text = 'Stream Name';
            app.StrmNDD = uidropdown(app.GridLayout34);
            app.StrmNDD.Items = {''};
            app.StrmNDD.Editable = 'on';
            app.StrmNDD.ValueChangedFcn = createCallbackFcn(app, @StrmNDDValueChanged, true);
            app.StrmNDD.Value = '';
            app.StrmRB = uibutton(app.GridLayout34, 'push');
            app.StrmRB.ButtonPushedFcn = createCallbackFcn(app, @StrmRBButtonPushed, true);
            app.StrmRB.Text = 'Remove';
            app.StrmTypeL = uilabel(app.GridLayout34);
            app.StrmTypeL.Text = 'Stream Type';
            app.StrmAB = uibutton(app.GridLayout34, 'push');
            app.StrmAB.ButtonPushedFcn = createCallbackFcn(app, @StrmABButtonPushed, true);
            app.StrmAB.Text = 'Add';
            app.NewStreamNameEditFieldLabel = uilabel(app.GridLayout34);
            app.NewStreamNameEditFieldLabel.Text = 'New Stream Name';
            app.NewStrmNF = uieditfield(app.GridLayout34, 'text');
            app.LiqInFlowCB = uicheckbox(app.GridLayout34);
            app.LiqInFlowCB.ValueChangedFcn = createCallbackFcn(app, @IOFlowUBButtonPushed, true);
            app.LiqInFlowCB.Text = 'Liquid Input Flow';
            app.LiqOutFlowCB = uicheckbox(app.GridLayout34);
            app.LiqOutFlowCB.ValueChangedFcn = createCallbackFcn(app, @IOFlowUBButtonPushed, true);
            app.LiqOutFlowCB.Text = 'Liquid Output Flow';
            app.GasInFlowCB = uicheckbox(app.GridLayout34);
            app.GasInFlowCB.ValueChangedFcn = createCallbackFcn(app, @IOFlowUBButtonPushed, true);
            app.GasInFlowCB.Text = 'Gas Input Flow';
            app.GasOutFlowCB = uicheckbox(app.GridLayout34);
            app.GasOutFlowCB.ValueChangedFcn = createCallbackFcn(app, @IOFlowUBButtonPushed, true);
            app.GasOutFlowCB.Text = 'Gas Output Flow';
            app.BubPhysSpecB = uibutton(app.GridLayout34, 'push');
            app.BubPhysSpecB.Text = 'Specify Bubble Physics Model';
            app.Button_10 = uibutton(app.GridLayout34, 'push');
            app.Button_10.Text = '?';
            app.MTP = uipanel(app.GridLayout27);
            app.MTP.Title = 'Mass Transfer Functions';
            app.GridLayout37 = uigridlayout(app.MTP);
            app.MTGovFuncL = uilabel(app.GridLayout37);
            app.MTGovFuncL.Interpreter = 'latex';
            app.MTGovFuncL.Text = '';
            app.MTSaveL = uilabel(app.GridLayout37);
            app.MTSaveL.Text = 'Saved';
            app.MTSysVarT = uitable(app.GridLayout37);
            app.MTSysVarT.ColumnName = {'System Variable'; 'Symbol'};
            app.MTSysVarT.RowName = {};
            app.ComponentDropDownLabel_2 = uilabel(app.GridLayout37);
            app.ComponentDropDownLabel_2.Text = 'Component';
            app.MTCompDD = uidropdown(app.GridLayout37);
            app.MTCompDD.Items = {''};
            app.MTCompDD.Editable = 'on';
            app.MTCompDD.ValueChangedFcn = createCallbackFcn(app, @MTCompDDValueChanged, true);
            app.MTCompDD.Value = '';
            app.GoverningEquationofLabel = uilabel(app.GridLayout37);
            app.GoverningEquationofLabel.Text = 'Phase A:';
            app.MTPhaseADD = uidropdown(app.GridLayout37);
            app.MTPhaseADD.Items = {''};
            app.MTPhaseADD.Editable = 'on';
            app.MTPhaseADD.ValueChangedFcn = createCallbackFcn(app, @MTPhaseDDValueChanged, true);
            app.MTPhaseADD.Tag = 'A';
            app.MTPhaseADD.Value = '';
            app.MTPhaseBL = uilabel(app.GridLayout37);
            app.MTPhaseBL.Text = 'Phase B:';
            app.MTPhaseBDD = uidropdown(app.GridLayout37);
            app.MTPhaseBDD.Items = {''};
            app.MTPhaseBDD.Editable = 'on';
            app.MTPhaseBDD.ValueChangedFcn = createCallbackFcn(app, @MTPhaseDDValueChanged, true);
            app.MTPhaseBDD.Tag = 'B';
            app.MTPhaseBDD.Value = '';
            app.MTGovFuncVF = uieditfield(app.GridLayout37, 'text');
            app.MTExpRefB = uibutton(app.GridLayout37, 'push');
            app.MTExpRefB.Text = 'Open Expressions Reference';
            app.MTFuncTypeDD = uidropdown(app.GridLayout37);
            app.MTFuncTypeDD.Items = {'Monod', 'Linear', 'Custom'};
            app.MTFuncTypeDD.Editable = 'on';
            app.MTFuncTypeDD.Value = 'Monod';
            app.MTFuncNewAB = uibutton(app.GridLayout37, 'push');
            app.MTFuncNewAB.ButtonPushedFcn = createCallbackFcn(app, @MTFuncNewABButtonPushed, true);
            app.MTFuncNewAB.Text = 'Add Expression';
            app.ExpressionDropDown_2Label = uilabel(app.GridLayout37);
            app.ExpressionDropDown_2Label.Text = 'Expression';
            app.MTPWDD = uidropdown(app.GridLayout37);
            app.MTPWDD.Items = {''};
            app.MTPWDD.Editable = 'on';
            app.MTPWDD.Value = '';
            app.MTPWAB = uibutton(app.GridLayout37, 'push');
            app.MTPWAB.ButtonPushedFcn = createCallbackFcn(app, @MTPWABButtonPushed, true);
            app.MTPWAB.Text = 'Add';
            app.MTPWRB = uibutton(app.GridLayout37, 'push');
            app.MTPWRB.ButtonPushedFcn = createCallbackFcn(app, @MTPWRBButtonPushed, true);
            app.MTPWRB.Text = 'Remove';
            app.MTPiecewiseT = uitable(app.GridLayout37);
            app.MTPiecewiseT.ColumnName = {'Lower Bound'; 'Variable'; 'Upper Bound'; 'Value Outside Bounds'};
            app.MTPiecewiseT.RowName = {};
            app.ElseDropDown_2Label = uilabel(app.GridLayout37);
            app.ElseDropDown_2Label.Text = 'Else';
            app.MTElseValDD = uidropdown(app.GridLayout37);
            app.MTElseValDD.Items = {'0', '1'};
            app.MTElseValDD.Editable = 'on';
            app.MTElseValDD.Value = '0';
            app.MTUpLimVF = uieditfield(app.GridLayout37, 'text');
            app.MTPWVarDD = uidropdown(app.GridLayout37);
            app.MTPWVarDD.Items = {'t', 'T', 'P', 'V', 'I', 'I_0'};
            app.MTPWVarDD.Editable = 'on';
            app.MTPWVarDD.Value = 't';
            app.MTLowLimVF = uieditfield(app.GridLayout37, 'text');
            app.IfLabel = uilabel(app.GridLayout37);
            app.IfLabel.Text = 'If';
            app.MTHelperAB = uibutton(app.GridLayout37, 'push');
            app.MTHelperAB.ButtonPushedFcn = createCallbackFcn(app, @HelperABButtonPushed, true);
            app.MTHelperAB.Text = 'Add New';
            app.MTHelperNameF = uieditfield(app.GridLayout37, 'text');
            app.MTHelperNameF.ValueChangingFcn = createCallbackFcn(app, @HelperNameFValueChanging, true);
            app.MTHelperNameF.Tag = 'MT';
            app.MTHelperSymF = uieditfield(app.GridLayout37, 'text');
            app.MTHelperSymF.ValueChangingFcn = createCallbackFcn(app, @HelperSymFValueChanging, true);
            app.MTHelperSymF.Tag = 'MT';
            app.MTHelperValF = uieditfield(app.GridLayout37, 'text');
            app.MTHelperValF.ValueChangingFcn = createCallbackFcn(app, @HelperValFValueChanging, true);
            app.MTHelperValF.Tag = 'MT';
            app.Label_13 = uilabel(app.GridLayout37);
            app.Label_13.Text = ':';
            app.Label_14 = uilabel(app.GridLayout37);
            app.Label_14.Text = '=';
            app.MTHelperFuncL = uilabel(app.GridLayout37);
            app.MTHelperFuncL.Interpreter = 'latex';
            app.MTHelperFuncL.Text = '';
            app.MTHelperSaveL = uilabel(app.GridLayout37);
            app.MTHelperSaveL.Text = 'Saved';
            app.MTHelperDD = uidropdown(app.GridLayout37);
            app.MTHelperDD.Items = {''};
            app.MTHelperDD.Editable = 'on';
            app.MTHelperDD.ValueChangedFcn = createCallbackFcn(app, @HelperDDValueChanged, true);
            app.MTHelperDD.Tag = 'MT';
            app.MTHelperDD.Value = '';
            app.MTHelperRB = uibutton(app.GridLayout37, 'push');
            app.MTHelperRB.ButtonPushedFcn = createCallbackFcn(app, @HelperRBButtonPushed, true);
            app.MTHelperRB.Tag = 'MT';
            app.MTHelperRB.Text = 'Remove';
            app.MTHelperUB = uibutton(app.GridLayout37, 'push');
            app.MTHelperUB.ButtonPushedFcn = createCallbackFcn(app, @HelperUBButtonPushed, true);
            app.MTHelperUB.Tag = 'MT';
            app.MTHelperUB.Text = 'Update';
            app.MTLowLimOpDD = uidropdown(app.GridLayout37);
            app.MTLowLimOpDD.Items = {'<=', '<'};
            app.MTLowLimOpDD.Value = '<=';
            app.MTUpLimOpDD = uidropdown(app.GridLayout37);
            app.MTUpLimOpDD.Items = {'<=', '<'};
            app.MTUpLimOpDD.Value = '<=';
            app.Button_11 = uibutton(app.GridLayout37, 'push');
            app.Button_11.Text = '?';
            app.MTModelUB = uibutton(app.GridLayout37, 'push');
            app.MTModelUB.ButtonPushedFcn = createCallbackFcn(app, @MTModelUBButtonPushed, true);
            app.MTModelUB.Enable = 'off';
            app.MTModelUB.Text = 'Update';
            app.GoverningFunctionsTab = uitab(app.TabGroup);
            app.GoverningFunctionsTab.Title = 'Governing Functions';
            app.GridLayout = uigridlayout(app.GoverningFunctionsTab);
            app.ComponentDropDownLabel_3 = uilabel(app.GridLayout);
            app.ComponentDropDownLabel_3.Text = 'Component';
            app.InteractCompDD = uidropdown(app.GridLayout);
            app.InteractCompDD.Items = {};
            app.InteractCompDD.Editable = 'on';
            app.InteractCompDD.ValueChangedFcn = createCallbackFcn(app, @InteractCompDDValueChanged, true);
            app.InteractCompDD.Value = {};
            app.GovFuncPanel = uipanel(app.GridLayout);
            app.GovFuncPanel.Title = 'Governing Function: ';
            app.GridLayout18 = uigridlayout(app.GovFuncPanel);
            app.GovFuncL = uilabel(app.GridLayout18);
            app.GovFuncL.Interpreter = 'latex';
            app.GovFuncL.Text = '';
            app.SaveL = uilabel(app.GridLayout18);
            app.SaveL.Text = 'Saved';
            app.SysVarT = uitable(app.GridLayout);
            app.SysVarT.ColumnName = {'System Variable'; 'Expression'};
            app.SysVarT.RowName = {};
            app.SubFuncPanel = uipanel(app.GridLayout);
            app.SubFuncPanel.Title = 'Edit Functions';
            app.GridLayout19 = uigridlayout(app.SubFuncPanel);
            app.FuncTypeDD = uidropdown(app.GridLayout19);
            app.FuncTypeDD.Items = {'Monod', 'Linear', 'Custom'};
            app.FuncTypeDD.Editable = 'on';
            app.FuncTypeDD.Value = 'Monod';
            app.FuncNewAB = uibutton(app.GridLayout19, 'push');
            app.FuncNewAB.ButtonPushedFcn = createCallbackFcn(app, @FuncNewABButtonPushed, true);
            app.FuncNewAB.Text = 'Add Expression';
            app.ModelUB = uibutton(app.GridLayout19, 'push');
            app.ModelUB.ButtonPushedFcn = createCallbackFcn(app, @ModelUBButtonPushed, true);
            app.ModelUB.Enable = 'off';
            app.ModelUB.Text = 'Update';
            app.GovFuncVF = uieditfield(app.GridLayout19, 'text');
            app.GovFuncVF.ValueChangingFcn = createCallbackFcn(app, @GovFuncVFValueChanging, true);
            app.OpenExpressionsReferenceButton = uibutton(app.GridLayout19, 'push');
            app.OpenExpressionsReferenceButton.Text = 'Open Expressions Reference';
            app.PiecewiseLimsP = uipanel(app.GridLayout);
            app.PiecewiseLimsP.Title = 'Set Piecewise Limits on Functions';
            app.GridLayout20 = uigridlayout(app.PiecewiseLimsP);
            app.IfLabel_2 = uilabel(app.GridLayout20);
            app.IfLabel_2.Text = 'If';
            app.PiecewiseVarDD = uidropdown(app.GridLayout20);
            app.PiecewiseVarDD.Items = {'t', 'Option 2', 'Option 3', 'Option 4'};
            app.PiecewiseVarDD.Editable = 'on';
            app.PiecewiseVarDD.Value = 't';
            app.GovFuncPiecewiseT = uitable(app.GridLayout20);
            app.GovFuncPiecewiseT.ColumnName = {'Lower Bound'; 'Upper Bound'; 'Variable'; 'Value Outside Bounds'};
            app.GovFuncPiecewiseT.RowName = {};
            app.PiecewiseAB = uibutton(app.GridLayout20, 'push');
            app.PiecewiseAB.ButtonPushedFcn = createCallbackFcn(app, @PiecewiseABButtonPushed, true);
            app.PiecewiseAB.Text = 'Add';
            app.PiecewiseRB = uibutton(app.GridLayout20, 'push');
            app.PiecewiseRB.ButtonPushedFcn = createCallbackFcn(app, @PiecewiseRBButtonPushed, true);
            app.PiecewiseRB.Text = 'Remove';
            app.ExpressionDropDownLabel = uilabel(app.GridLayout20);
            app.ExpressionDropDownLabel.Text = 'Expression';
            app.PiecewiseDD = uidropdown(app.GridLayout20);
            app.PiecewiseDD.Items = {};
            app.PiecewiseDD.Editable = 'on';
            app.PiecewiseDD.Value = {};
            app.ElseDropDownLabel = uilabel(app.GridLayout20);
            app.ElseDropDownLabel.Text = 'Else';
            app.ElseValDD = uidropdown(app.GridLayout20);
            app.ElseValDD.Items = {'0', '1'};
            app.ElseValDD.Editable = 'on';
            app.ElseValDD.Value = '0';
            app.LowLimVF = uieditfield(app.GridLayout20, 'numeric');
            app.LowLimVF.AllowEmpty = 'on';
            app.LowLimVF.Value = [];
            app.UpLimVF = uieditfield(app.GridLayout20, 'numeric');
            app.UpLimVF.AllowEmpty = 'on';
            app.UpLimVF.Value = [];
            app.LowLimOpDD = uidropdown(app.GridLayout20);
            app.LowLimOpDD.Items = {'<=', '<'};
            app.LowLimOpDD.Value = '<=';
            app.UpLimOpDD = uidropdown(app.GridLayout20);
            app.UpLimOpDD.Items = {'<=', '<'};
            app.UpLimOpDD.Value = '<=';
            app.HelperFuncP = uipanel(app.GridLayout);
            app.HelperFuncP.Title = 'Helper Functions';
            app.GridLayout21 = uigridlayout(app.HelperFuncP);
            app.HelperDD = uidropdown(app.GridLayout21);
            app.HelperDD.Items = {};
            app.HelperDD.Editable = 'on';
            app.HelperDD.ValueChangedFcn = createCallbackFcn(app, @HelperDDValueChanged, true);
            app.HelperDD.Tag = 'GF';
            app.HelperDD.Value = {};
            app.HelperUB = uibutton(app.GridLayout21, 'push');
            app.HelperUB.ButtonPushedFcn = createCallbackFcn(app, @HelperUBButtonPushed, true);
            app.HelperUB.Tag = 'GF';
            app.HelperUB.Enable = 'off';
            app.HelperUB.Text = 'Update';
            app.HelperRB = uibutton(app.GridLayout21, 'push');
            app.HelperRB.ButtonPushedFcn = createCallbackFcn(app, @HelperRBButtonPushed, true);
            app.HelperRB.Tag = 'GF';
            app.HelperRB.Text = 'Remove';
            app.Label_15 = uilabel(app.GridLayout21);
            app.Label_15.Text = '=';
            app.HelperValF = uieditfield(app.GridLayout21, 'text');
            app.HelperValF.ValueChangingFcn = createCallbackFcn(app, @HelperValFValueChanging, true);
            app.HelperValF.Tag = 'GF';
            app.HelperSymF = uieditfield(app.GridLayout21, 'text');
            app.HelperSymF.ValueChangingFcn = createCallbackFcn(app, @HelperSymFValueChanging, true);
            app.HelperSymF.Tag = 'GF';
            app.HelperNameF = uieditfield(app.GridLayout21, 'text');
            app.HelperNameF.ValueChangingFcn = createCallbackFcn(app, @HelperNameFValueChanging, true);
            app.HelperNameF.Tag = 'GF';
            app.Label_16 = uilabel(app.GridLayout21);
            app.Label_16.Text = ':';
            app.HelperAB = uibutton(app.GridLayout21, 'push');
            app.HelperAB.ButtonPushedFcn = createCallbackFcn(app, @HelperABButtonPushed, true);
            app.HelperAB.Text = 'Add New';
            app.HelperFuncL = uilabel(app.GridLayout21);
            app.HelperFuncL.Interpreter = 'latex';
            app.HelperFuncL.Text = '';
            app.HelperSaveL = uilabel(app.GridLayout21);
            app.HelperSaveL.Text = 'Saved';
            app.ModelReviewTab = uitab(app.TabGroup);
            app.ModelReviewTab.Title = 'Model Review';
            app.GridLayout39 = uigridlayout(app.ModelReviewTab);
            app.ModelSolverSettingsButton = uibutton(app.GridLayout39, 'push');
            app.ModelSolverSettingsButton.Text = 'Model Solver Settings';
            app.ModelFunctionsPanel = uipanel(app.GridLayout39);
            app.ModelFunctionsPanel.Title = 'Model Functions';
            app.SummaryL = uilabel(app.ModelFunctionsPanel);
            app.SummaryL.Interpreter = 'latex';
            app.SummaryL.Text = '';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout7 = uigridlayout(app.ParametersTab);
            app.ParamT = uitable(app.GridLayout7);
            app.ParamT.ColumnName = {'Number'; 'Function'; 'Symbol'; 'Name'; 'Value'; 'Unit'; 'Editable'};
            app.ParamT.RowName = {};
            app.ParamT.ColumnSortable = [true true true true true true false];
            app.ParameterNumberLabel = uilabel(app.GridLayout7);
            app.ParameterNumberLabel.Text = 'Parameter Number';
            app.ParamNumDD = uidropdown(app.GridLayout7);
            app.ParamNumDD.Items = {};
            app.ParamNumDD.Editable = 'on';
            app.ParamNumDD.ValueChangedFcn = createCallbackFcn(app, @ParamNumDDValueChanged, true);
            app.ParamNumDD.Value = {};
            app.ParamValF = uieditfield(app.GridLayout7, 'numeric');
            app.ParamUnitDD = uidropdown(app.GridLayout7);
            app.ParamUnitDD.Items = {'', 'g/L'};
            app.ParamUnitDD.Editable = 'on';
            app.ParamUnitDD.Value = 'g/L';
            app.ParamUB = uibutton(app.GridLayout7, 'push');
            app.ParamUB.ButtonPushedFcn = createCallbackFcn(app, @ParamUBPushed, true);
            app.ParamUB.Text = 'Update';
            app.SpeciesChemicalLabel = uilabel(app.GridLayout7);
            app.SpeciesChemicalLabel.Text = 'Species/Chemical';
            app.CompParamDD = uidropdown(app.GridLayout7);
            app.CompParamDD.Items = {};
            app.CompParamDD.Editable = 'on';
            app.CompParamDD.ValueChangedFcn = createCallbackFcn(app, @CompParamDDValueChanged, true);
            app.CompParamDD.Value = {};
            app.ParameterNameEditFieldLabel = uilabel(app.GridLayout7);
            app.ParameterNameEditFieldLabel.Text = 'Parameter Name';
            app.ParamNF = uieditfield(app.GridLayout7, 'text');
            app.ParameterSymbolDropDownLabel = uilabel(app.GridLayout7);
            app.ParameterSymbolDropDownLabel.Text = 'Parameter Symbol';
            app.ParamSymDD = uidropdown(app.GridLayout7);
            app.ParamSymDD.Items = {};
            app.ParamSymDD.Editable = 'on';
            app.ParamSymDD.ValueChangedFcn = createCallbackFcn(app, @ParamSymDDValueChanged, true);
            app.ParamSymDD.Value = {};
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.GridLayout32 = uigridlayout(app.RegressionTab);
            app.GridLayout32.Tag = 'unmatch';
            app.RegPlot = uiaxes(app.GridLayout32);
            title(app.RegPlot, 'Regression')
            xlabel(app.RegPlot, 'Time')
            ylabel(app.RegPlot, 'Dependent Variable(s)')
            zlabel(app.RegPlot, 'Z')
            app.RegParamDD = uidropdown(app.GridLayout32);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.Value = {};
            app.SaveRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveRegParamBButtonPushed, true);
            app.SaveRegParamB.Text = 'Save';
            app.ParametersDropDownLabel = uilabel(app.GridLayout32);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.RegParamAB = uibutton(app.GridLayout32, 'push');
            app.RegParamAB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamAB.Tag = 'Add';
            app.RegParamAB.Text = 'Add';
            app.RegParamRB = uibutton(app.GridLayout32, 'push');
            app.RegParamRB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamRB.Tag = 'Remove';
            app.RegParamRB.Text = 'Remove';
            app.SystemVariableDropDownLabel = uilabel(app.GridLayout32);
            app.SystemVariableDropDownLabel.Text = 'System Variable';
            app.RegSysVarDD = uidropdown(app.GridLayout32);
            app.RegSysVarDD.Items = {};
            app.RegSysVarDD.Editable = 'on';
            app.RegSysVarDD.Value = {};
            app.ImportedDataDropDownLabel = uilabel(app.GridLayout32);
            app.ImportedDataDropDownLabel.Text = 'Imported Data';
            app.ImportedDataDD = uidropdown(app.GridLayout32);
            app.ImportedDataDD.Items = {};
            app.ImportedDataDD.Editable = 'on';
            app.ImportedDataDD.Value = {};
            app.VarDataMatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataMatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataMatchB.Tag = 'match';
            app.VarDataMatchB.Text = 'Match';
            app.RegParamT = uitable(app.GridLayout32);
            app.RegParamT.ColumnName = {'Parameter'; 'Component'; 'Function'; 'Value'; 'Regressed Value'};
            app.RegParamT.RowName = {};
            app.RunRegB = uibutton(app.GridLayout32, 'push');
            app.RunRegB.ButtonPushedFcn = createCallbackFcn(app, @RunRegBPushed, true);
            app.RunRegB.Enable = 'off';
            app.RunRegB.Text = 'Run Regression';
            app.ParameterDropDownLabel_2 = uilabel(app.GridLayout32);
            app.ParameterDropDownLabel_2.Text = 'Parameter';
            app.SaveRegParamDD = uidropdown(app.GridLayout32);
            app.SaveRegParamDD.Items = {};
            app.SaveRegParamDD.Editable = 'on';
            app.SaveRegParamDD.Value = {};
            app.SaveAllRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveAllRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveAllRegParamBButtonPushed, true);
            app.SaveAllRegParamB.Text = 'Save All';
            app.ImportDataB = uibutton(app.GridLayout32, 'push');
            app.ImportDataB.ButtonPushedFcn = createCallbackFcn(app, @ImportBButtonPushed, true);
            app.ImportDataB.Text = 'Import Data';
            app.DependentVariableLabel = uilabel(app.GridLayout32);
            app.DependentVariableLabel.Text = 'Dependent Variable';
            app.RegDVDD = uidropdown(app.GridLayout32);
            app.RegDVDD.Items = {};
            app.RegDVDD.Editable = 'on';
            app.RegDVDD.Value = {};
            app.RegDVAB = uibutton(app.GridLayout32, 'push');
            app.RegDVAB.ButtonPushedFcn = createCallbackFcn(app, @RegDVPlotUpdate, true);
            app.RegDVAB.Tag = 'add';
            app.RegDVAB.Text = 'Plot';
            app.RegVarDataT = uitable(app.GridLayout32);
            app.RegVarDataT.ColumnName = {'System Variable Name'; 'Imported Variable Name'};
            app.RegVarDataT.RowName = {};
            app.ComponentDropDown_2Label = uilabel(app.GridLayout32);
            app.ComponentDropDown_2Label.Text = 'Component';
            app.RegCompParamDD = uidropdown(app.GridLayout32);
            app.RegCompParamDD.Items = {};
            app.RegCompParamDD.Editable = 'on';
            app.RegCompParamDD.ValueChangedFcn = createCallbackFcn(app, @RegCompParamDDValueChanged, true);
            app.RegCompParamDD.Value = {};
            app.VarDataUnmatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataUnmatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataUnmatchB.Text = 'Unmatch';
            app.RegSpecsB = uibutton(app.GridLayout32, 'push');
            app.RegSpecsB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecsBButtonPushed, true);
            app.RegSpecsB.Enable = 'off';
            app.RegSpecsB.Text = 'Regression Specifications';
            app.RegAnalysisB = uibutton(app.GridLayout32, 'push');
            app.RegAnalysisB.ButtonPushedFcn = createCallbackFcn(app, @RegAnalysisBPushed, true);
            app.RegAnalysisB.Enable = 'off';
            app.RegAnalysisB.Text = 'Goodness of Fit Analysis';
            app.PlotCustomizationTab = uitab(app.TabGroup);
            app.PlotCustomizationTab.Title = 'Plot Customization';
            app.GridLayout4 = uigridlayout(app.PlotCustomizationTab);
            app.PlotsDropDownLabel = uilabel(app.GridLayout4);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotRB = uibutton(app.GridLayout4, 'push');
            app.PlotRB.Text = 'Remove';
            app.AxisSpecificationsP = uipanel(app.GridLayout4);
            app.AxisSpecificationsP.Title = 'Axis Specifications';
            app.GridLayout22 = uigridlayout(app.AxisSpecificationsP);
            app.XAxisTitleLabel = uilabel(app.GridLayout22);
            app.XAxisTitleLabel.Text = 'X Axis Title';
            app.XAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.XAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleFValueChanged, true);
            app.XAxTitleF.Tag = 'X';
            app.YAxisTitleLabel = uilabel(app.GridLayout22);
            app.YAxisTitleLabel.Text = 'Y Axis Title';
            app.YAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.YAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleFValueChanged, true);
            app.YAxTitleF.Tag = 'Y';
            app.ZAxisTitleEditFieldLabel = uilabel(app.GridLayout22);
            app.ZAxisTitleEditFieldLabel.Text = 'Z-Axis Title';
            app.ZAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.ZAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleFValueChanged, true);
            app.ZAxTitleF.Tag = 'Z';
            app.XAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.XAxDefaultRCB.Text = 'Use Default Range';
            app.NumberofAxesDropDownLabel = uilabel(app.GridLayout22);
            app.NumberofAxesDropDownLabel.Text = 'Number of Axes';
            app.NbAxesDD = uidropdown(app.GridLayout22);
            app.NbAxesDD.Items = {'2-Axis', '3-Axis'};
            app.NbAxesDD.ValueChangedFcn = createCallbackFcn(app, @NbAxesDDValueChanged, true);
            app.NbAxesDD.Value = '2-Axis';
            app.VariableDropDownLabel = uilabel(app.GridLayout22);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown_2Label = uilabel(app.GridLayout22);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.YAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.YAxDefaultRCB.Text = 'Use Default Range';
            app.VariableDropDown_3Label = uilabel(app.GridLayout22);
            app.VariableDropDown_3Label.Text = 'Variable';
            app.ZAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.ZAxDefaultRCB.Text = 'Use Default Range';
            app.LowerDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_3Label.Text = 'Lower Display Limit';
            app.UpperDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_3Label.Text = 'Upper Display Limit';
            app.LowerDisplayLimitDropDown_2Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_2Label.Text = 'Lower Display Limit';
            app.UpperDisplayLimitDropDown_2Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_2Label.Text = 'Upper Display Limit';
            app.LowerDisplayLimitDropDown_4Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_4Label.Text = 'Lower Display Limit';
            app.UpperDisplayLimitDropDown_4Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_4Label.Text = 'Upper Display Limit';
            app.Div3 = uilabel(app.GridLayout22);
            app.Div3.Text = '';
            app.Div2 = uilabel(app.GridLayout22);
            app.Div2.Text = '';
            app.Div1 = uilabel(app.GridLayout22);
            app.Div1.Text = '';
            app.XAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.XAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.XAxDispLimUB.Tag = 'X';
            app.XAxDispLimUB.Text = 'Update';
            app.YAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.YAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.YAxDispLimUB.Tag = 'Y';
            app.YAxDispLimUB.Text = 'Update';
            app.ZAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.ZAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.ZAxDispLimUB.Tag = 'Z';
            app.ZAxDispLimUB.Text = 'Update';
            app.YAxVarL = uilabel(app.GridLayout22);
            app.YAxVarL.Interpreter = 'latex';
            app.YAxVarL.Text = 'Variable(s): None';
            app.YAxVarAB = uibutton(app.GridLayout22, 'push');
            app.YAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.YAxVarAB.Tag = 'Y_add';
            app.YAxVarAB.Text = 'Add';
            app.YAxVarRB = uibutton(app.GridLayout22, 'push');
            app.YAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.YAxVarRB.Tag = 'Y_rm';
            app.YAxVarRB.Text = 'Remove';
            app.ZAxVarL = uilabel(app.GridLayout22);
            app.ZAxVarL.Interpreter = 'latex';
            app.ZAxVarL.Text = 'Variable(s): None';
            app.XAxVarL = uilabel(app.GridLayout22);
            app.XAxVarL.Interpreter = 'latex';
            app.XAxVarL.Text = 'Variable(s): None';
            app.XAxVarAB = uibutton(app.GridLayout22, 'push');
            app.XAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.XAxVarAB.Tag = 'X_add';
            app.XAxVarAB.Text = 'Add';
            app.XAxVarRB = uibutton(app.GridLayout22, 'push');
            app.XAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.XAxVarRB.Tag = 'X_rm';
            app.XAxVarRB.Text = 'Remove';
            app.ZAxVarAB = uibutton(app.GridLayout22, 'push');
            app.ZAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.ZAxVarAB.Tag = 'Z_add';
            app.ZAxVarAB.Text = 'Add';
            app.ZAxVarRB = uibutton(app.GridLayout22, 'push');
            app.ZAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.ZAxVarRB.Tag = 'Z_rm';
            app.ZAxVarRB.Text = 'Remove';
            app.XAxVarDD = uidropdown(app.GridLayout22);
            app.XAxVarDD.Items = {''};
            app.XAxVarDD.Editable = 'on';
            app.XAxVarDD.Value = '';
            app.YAxVarDD = uidropdown(app.GridLayout22);
            app.YAxVarDD.Items = {''};
            app.YAxVarDD.Editable = 'on';
            app.YAxVarDD.Value = '';
            app.ZAxVarDD = uidropdown(app.GridLayout22);
            app.ZAxVarDD.Items = {''};
            app.ZAxVarDD.Editable = 'on';
            app.ZAxVarDD.Value = '';
            app.YAxLoLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.YAxUpLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.XAxLoLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.XAxUpLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.ZAxLoLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.ZAxUpLimVF = uieditfield(app.GridLayout22, 'numeric');
            app.Label_18 = uilabel(app.GridLayout22);
            app.Label2 = uilabel(app.GridLayout22);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.GridLayout22);
            app.Label3.Text = 'Label3';
            app.Button_12 = uibutton(app.GridLayout22, 'push');
            app.Button_12.Text = '?';
            app.Button_13 = uibutton(app.GridLayout22, 'push');
            app.Button_13.Text = '?';
            app.Button_14 = uibutton(app.GridLayout22, 'push');
            app.Button_14.Text = '?';
            app.PlotInitCondP = uipanel(app.GridLayout4);
            app.PlotInitCondP.Title = 'Additional Specifications';
            app.GridLayout23 = uigridlayout(app.PlotInitCondP);
            app.SysVarInitCondT = uitable(app.GridLayout23);
            app.SysVarInitCondT.ColumnName = {'Variable'; 'Unit'};
            app.SysVarInitCondT.RowName = {};
            app.SysVarInitCondT.ColumnSortable = true;
            app.SysVarInitCondT.ColumnEditable = true;
            app.PlotICEvalSpecUB = uibutton(app.GridLayout23, 'push');
            app.PlotICEvalSpecUB.ButtonPushedFcn = createCallbackFcn(app, @PlotICEvalSpecUBButtonPushed, true);
            app.PlotICEvalSpecUB.Text = 'Update';
            app.AxisDropDownLabel = uilabel(app.GridLayout23);
            app.AxisDropDownLabel.Text = 'Axis: ';
            app.VariableDropDownLabel_2 = uilabel(app.GridLayout23);
            app.VariableDropDownLabel_2.Text = 'Variable: ';
            app.UnitsLabel = uilabel(app.GridLayout23);
            app.UnitsLabel.Text = 'Units: ';
            app.AddSpecL1 = uilabel(app.GridLayout23);
            app.AddSpecL1.Visible = 'off';
            app.AddSpecL1.Text = '';
            app.AddSpecL2 = uilabel(app.GridLayout23);
            app.AddSpecL2.Visible = 'off';
            app.AddSpecL2.Text = '';
            app.AddSpecL3 = uilabel(app.GridLayout23);
            app.AddSpecL3.Visible = 'off';
            app.AddSpecL3.Text = '';
            app.ICEvalAxisDD = uidropdown(app.GridLayout23);
            app.ICEvalAxisDD.Items = {''};
            app.ICEvalAxisDD.Editable = 'on';
            app.ICEvalAxisDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalAxisDDValueChanged, true);
            app.ICEvalAxisDD.Value = '';
            app.ICEvalVarDD = uidropdown(app.GridLayout23);
            app.ICEvalVarDD.Items = {''};
            app.ICEvalVarDD.Editable = 'on';
            app.ICEvalVarDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalVarDDValueChanged, true);
            app.ICEvalVarDD.Value = '';
            app.evalUnitsVF = uieditfield(app.GridLayout23, 'text');
            app.AddSpecVF1 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF1.AllowEmpty = 'on';
            app.AddSpecVF1.Value = [];
            app.AddSpecVF2 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF2.AllowEmpty = 'on';
            app.AddSpecVF2.Value = [];
            app.AddSpecVF3 = uieditfield(app.GridLayout23, 'numeric');
            app.AddSpecVF3.AllowEmpty = 'on';
            app.AddSpecVF3.Value = [];
            app.PlotGenerationP = uipanel(app.GridLayout4);
            app.PlotGenerationP.Title = 'Model Compilation';
            app.GridLayout24 = uigridlayout(app.PlotGenerationP);
            app.GenPlotB = uibutton(app.GridLayout24, 'push');
            app.GenPlotB.ButtonPushedFcn = createCallbackFcn(app, @GenPlotBButtonPushed, true);
            app.GenPlotB.Text = 'Compile Model';
            app.OptionsL = uilabel(app.GridLayout24);
            app.OptionsL.Text = 'Options';
            app.ClosePrevPlotsCB = uicheckbox(app.GridLayout24);
            app.ClosePrevPlotsCB.Text = 'Close Previous Plots';
            app.SelectPlotsL = uilabel(app.GridLayout24);
            app.SelectPlotsL.Text = 'Select Plots:';
            app.PlotDispTree = uitree(app.GridLayout24, 'checkbox');
            app.PlotDispTree.Editable = 'on';
            app.PlotDownloadP = uipanel(app.GridLayout4);
            app.PlotDownloadP.Title = 'Plot Download';
            app.GridLayout25 = uigridlayout(app.PlotDownloadP);
            app.PlotDirL = uilabel(app.GridLayout25);
            app.PlotDirL.Text = 'Directory: None Selected';
            app.PlotDownloadDirB = uibutton(app.GridLayout25, 'push');
            app.PlotDownloadDirB.ButtonPushedFcn = createCallbackFcn(app, @PlotDownloadDirBButtonPushed, true);
            app.PlotDownloadDirB.Text = 'Select Directory';
            app.FileTypeDropDownLabel = uilabel(app.GridLayout25);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDD = uidropdown(app.GridLayout25);
            app.FileTypeDD.Items = {'.png', '.jpg', '.jpeg', '.fig'};
            app.FileTypeDD.Value = '.png';
            app.PlotAB = uibutton(app.GridLayout4, 'push');
            app.PlotAB.ButtonPushedFcn = createCallbackFcn(app, @PlotABButtonPushed, true);
            app.PlotAB.Text = 'Add';
            app.PlotTitleDD = uidropdown(app.GridLayout4);
            app.PlotTitleDD.Items = {''};
            app.PlotTitleDD.Editable = 'on';
            app.PlotTitleDD.ValueChangedFcn = createCallbackFcn(app, @PlotTitleDDValueChanged, true);
            app.PlotTitleDD.Value = '';
            app.TitleEditFieldLabel_2 = uilabel(app.GridLayout4);
            app.TitleEditFieldLabel_2.Text = 'Title';
            app.PlotTitleF = uieditfield(app.GridLayout4, 'text');
            app.PlotTitleF.ValueChangedFcn = createCallbackFcn(app, @PlotTitleFValueChanged, true);
            app.Label4 = uilabel(app.GridLayout4);
            app.Label4.Text = 'Label4';
            app.ofSubplotRowsEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSubplotRowsEditFieldLabel.Text = '# of Subplot Rows:';
            app.SubplotRVF = uieditfield(app.GridLayout4, 'numeric');
            app.SubplotRVF.Limits = [1 Inf];
            app.SubplotRVF.Value = 1;
            app.SubplotUB = uibutton(app.GridLayout4, 'push');
            app.SubplotUB.ButtonPushedFcn = createCallbackFcn(app, @SubplotUBButtonPushed, true);
            app.SubplotUB.Text = 'Update';
            app.PlotGroupDropDownLabel = uilabel(app.GridLayout4);
            app.PlotGroupDropDownLabel.Text = 'Plot Group:';
            app.SubplotGroupDD = uidropdown(app.GridLayout4);
            app.SubplotGroupDD.Items = {'1'};
            app.SubplotGroupDD.ValueChangedFcn = createCallbackFcn(app, @SubplotGroupDDValueChanged, true);
            app.SubplotGroupDD.Value = '1';
            app.ofSubplotColumnsEditFieldLabel = uilabel(app.GridLayout4);
            app.ofSubplotColumnsEditFieldLabel.Text = '# of Subplot Columns:';
            app.SubplotCVF = uieditfield(app.GridLayout4, 'numeric');
            app.SubplotCVF.Value = 1;
            app.PlotPositionDropDownLabel = uilabel(app.GridLayout4);
            app.PlotPositionDropDownLabel.Text = 'Plot Position:';
            app.PlotPosDD = uidropdown(app.GridLayout4);
            app.PlotPosDD.Items = {'1'};
            app.PlotPosDD.ValueChangedFcn = createCallbackFcn(app, @PlotPosDDValueChanged, true);
            app.PlotPosDD.Value = '1';
            app.SubplotAB = uibutton(app.GridLayout4, 'push');
            app.SubplotAB.ButtonPushedFcn = createCallbackFcn(app, @SubplotABPushed, true);
            app.SubplotAB.Text = 'Add Subplot';
            app.SubplotDropDownLabel = uilabel(app.GridLayout4);
            app.SubplotDropDownLabel.Text = 'Subplot:';
            app.SubplotDD = uidropdown(app.GridLayout4);
            app.SubplotDD.Items = {'1'};
            app.SubplotDD.ValueChangedFcn = createCallbackFcn(app, @SubplotDDValueChanged, true);
            app.SubplotDD.Value = '1';
            app.SubplotRB = uibutton(app.GridLayout4, 'push');
            app.SubplotRB.ButtonPushedFcn = createCallbackFcn(app, @SubplotRBButtonPushed, true);
            app.SubplotRB.Text = 'Remove Subplot';
            app.ModelResultsExportPanel = uipanel(app.GridLayout4);
            app.ModelResultsExportPanel.Title = 'Model Results Export';
            app.GridLayout38 = uigridlayout(app.ModelResultsExportPanel);
            app.DataExportB = uibutton(app.GridLayout38, 'push');
            app.DataExportB.ButtonPushedFcn = createCallbackFcn(app, @DataExportBButtonPushed, true);
            app.DataExportB.Text = 'Export Data';
            app.DataExportDirB = uibutton(app.GridLayout38, 'push');

            app.DataExportDirB.ButtonPushedFcn = createCallbackFcn(app, @DataExportDirBButtonPushed, true);
            app.DataExportDirB.Text = 'Select Directory';
            app.DataExportDIrL = uilabel(app.GridLayout38);
            app.DataExportDIrL.Text = 'Directory: None Selected';
            app.ContextMenu2 = uicontextmenu(app.MAGMAUIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.MAGMAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAGMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAGMAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAGMAUIFigure)
        end
    end
end