classdef RegSpecsWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        RegSpecsP                      matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        FunValCheckCB                  matlab.ui.control.CheckBox
        MaxIterSpin                    matlab.ui.control.Spinner
        MaximumIterationsSpinnerLabel  matlab.ui.control.Label
        TuningConstDD                  matlab.ui.control.DropDown
        TuningConstantDropDownLabel    matlab.ui.control.Label
        TuneConstL                     matlab.ui.control.Label
        WtFuncL                        matlab.ui.control.Label
        WFDD                           matlab.ui.control.DropDown
        WeightFunctionDropDownLabel    matlab.ui.control.Label
        TolXF                          matlab.ui.control.NumericEditField
        TerminationToleranceonEstimatedCoefficientsEditFieldLabel  matlab.ui.control.Label
        TolFunF                        matlab.ui.control.NumericEditField
        TerminationToleranceonResidualSumofSquaresEditFieldLabel  matlab.ui.control.Label
        ParameterSpecificationsPanel   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        RegParamSpecUB                 matlab.ui.control.Button
        DerivStepDD                    matlab.ui.control.DropDown
        RelativeDifferenceforFiniteDifferenceGradientDropDownLabel  matlab.ui.control.Label
        DerivStepL                     matlab.ui.control.Label
        ParamIGLL3                     matlab.ui.control.Label
        ParamIGDD3                     matlab.ui.control.DropDown
        withDropDownLabel              matlab.ui.control.Label
        ParamIGDD2                     matlab.ui.control.DropDown
        toDropDownLabel                matlab.ui.control.Label
        ParamIGDD1                     matlab.ui.control.DropDown
        ParameterInitialGuessDropDownLabel  matlab.ui.control.Label
        RegParamDD                     matlab.ui.control.DropDown
        RegressionParameterDropDownLabel  matlab.ui.control.Label
        RegSpecUB                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp
        regSpecs % struct of regression specifications
        regParamList % List of parameter names
        RegSpecB;
    end

    % ### FIXME: add error model functionality
    
    methods (Access = private)
        

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, regSpecs, regParamList, RegSpecB)
            app.CallingApp = mainapp;
            app.regSpecs = regSpecs;
            app.regSpecs.paramIGs = zeros(size(regParamList,1),1);
            app.regSpecs.DerivStep = zeros(size(regParamList,1),1);
            test13 = app.regSpecs.DerivStep
            for k=1:1:size(regParamList,1)
                app.regSpecs.paramIGs(k,1) = regParamList{k,2};
                app.regSpecs.DerivStep(k,1) = eps^(1/3);
            end
            app.regParamList = regParamList;
            app.RegParamDD.Items = regParamList(:,1);
            app.DerivStepL.Text = string(eps.^(1./3));
            app.ParamIGDD1.Items = string(regParamList{1,2});
            app.ParamIGDD2.Items = string(regParamList{1,2});
            app.ParamIGDD3.Items = ["1"]; %#ok<NBRAK> 
            app.RegSpecB = RegSpecB;
            test12 = app.regSpecs.DerivStep
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.RegSpecB.Enable = true;
            delete(app);
        end

        % Button pushed function: RegSpecUB
        function RegSpecUBPushed(app, event)
            app.regSpecs.params = app.regParamList{:,1};
            
            app.regSpecs.TolFun = app.TolFunF.Value;
            app.regSpecs.TolX = app.TolXF.Value;

            app.regSpecs.MaxIter = app.MaxIterSpin.Value;
            if app.FunValCheckCB.Value, app.regSpecs.FunValCheck = 'on';
            else, app.regSpecs.FunValCheck = 'off'; end

            updateRegSpecs(app.CallingApp,app.regSpecs); 
        end

        % Button pushed function: RegParamSpecUB
        function RegParamSpecUBButtonPushed(app, event)
            loLim = double(string(app.ParamIGDD1.Value));
%             upLim = double(string(app.ParamIGDD2.Value));
%             nbPts = double(string(app.ParamIGDD3.Value));
            derivStep = double(string(app.DerivStepDD.Value));
            paramName = app.RegParamDD.Value;
            for k=1:1:size(app.regParamList,1)
                if strcmp(app.regParamList{k,1},paramName)
                    % ### FIXME: upgrade to enable user to try multiple
                    % starting guesses for each parameter
%                     app.regSpecs.paramIGs{k} = linspace(loLim,upLim,nbPts);
                    app.regSpecs.paramIGs(k,1) = loLim;
                    test10 = app.regSpecs.DerivStep
                    if isnan(derivStep)
                        app.regSpecs.DerivStep(k,1) = eps^(1/3);
                    else
                        app.regSpecs.DerivStep(k,1) = derivStep;
                    end
                    test10 = app.regSpecs.DerivStep
                end
            end
        end

        % Value changed function: RegParamDD
        function RegParamDDValueChanged(app, event)
            paramName = string(app.RegParamDD.Value);
            for k=1:1:length(app.regParamList)
                if strcmp(app.regParamList{k,1},paramName)
                    app.ParamIGDD1.Items = string(app.regSpecs.paramIGs(k,1));
                    app.ParamIGDD1.Value = string(app.regSpecs.paramIGs(k,1));
%                     app.ParamIGDD2.Items = app.regSpecs.paramIGs{k}(2);
%                     app.ParamIGDD2.Value = app.regSpecs.paramIGs{k}(2);
%                     app.ParamIGDD3.Items = app.regSpecs.paramIGs{k}(3);
%                     app.ParamIGDD3.Value = app.regSpecs.paramIGs{k}(3);
                    if app.regSpecs.DerivStep(k,1) == eps^(1/3)
                        app.DerivStepDD.Items = {'Default'};
                        app.DerivStepDD.Value = 'Default';
                    else
                        app.DerivStepDD.Items = app.regSpecs.DerivStep(k,1);
                        app.DerivStepDD.Value = app.regSpecs.DerivStep(k,1);
                    end
                end
            end
        end

        % Value changed function: DerivStepDD
        function DerivStepDDValueChanged(app, event)
            value = app.DerivStepDD.Value;
            if value == "Default"
                app.DerivStepL.Text = eps^(1/3);
            else
                app.DerivStepL.Text = value;
            end

            app.AddNewValToDD(event);
        end

        % Value changed function: ParamIGDD1, ParamIGDD2, ParamIGDD3
        function AddNewValToDD(app, event)
            value = event.Source.Value;
            event.Source.Items = [event.Source.Items,{value}];
        end

        % Value changed function: TuningConstDD, WFDD
        function WFDDValueChanged(app, event)
            wtFuncName = app.WFDD.Value;
            switch wtFuncName
                case 'None'
                    wtFunc = '';
                    defTuneConst = [];
                    wtFuncText = "N/A";
                    app.TuningConstDD.Value = 'Default';
                    app.TuneConstL.Text = "N/A";
                case 'Andrews'
                    wtFunc = 'andrews';
                    defTuneConst = 1.339;
                    wtFuncText = "$w(r) = I[|r|<pi]*\frac{sin(r)}{r}$";
                case 'Bisquare'
                    wtFunc = 'bisquare';
                    defTuneConst = 4.685;
                    wtFuncText = "$w(r) = I[|r|<1]*(1-r^2)^2$";
                case 'Cauchy'
                    wtFunc = 'cauchy';
                    defTuneConst = 2.385;
                    wtFuncText = "$w(r) = \frac{1}{(1+r^2)}$";
                case 'Fair'
                    wtFunc = 'fair';
                    defTuneConst = 1.400;
                    wtFuncText = "$w(r) = \frac{1}{(1+|r|)}$";
                case 'Huber'
                    wtFunc = 'huber';
                    defTuneConst = 1.345;
                    wtFuncText = "$w(r) = \frac{1}{\max(1,|r|)}$";
                case 'Logistic'
                    wtFunc = 'logistic';
                    defTuneConst = 1.205;
                    wtFuncText = "$w(r) = \frac{\tanh(r)}{r}$";
                case 'Talwar'
                    wtFunc = 'talwar';
                    defTuneConst = 2.795;
                    wtFuncText = "$w(r) = I[|r|<1]$";
                case 'Welsch'
                    wtFunc = 'welsch';
                    defTuneConst = 2.985;
                    wtFuncText = "$w(r) = e^{-r^2}$";
            end

            app.regSpecs.RobustWgtFun = wtFunc;
            if app.TuningConstDD.Value == "Default"
                app.regSpecs.Tune = defTuneConst;
                if defTuneConst == double.empty
                    defTuneConst = "N/A";
                end
                app.TuneConstL.Text = defTuneConst;
            else
                app.regSpecs.Tune = double(string(app.TuningConstDD.Value));
                app.TuneConstL.Text = double(string(app.TuningConstDD.Value));
            end

            app.WtfuncL.Text = wtFuncText;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RegSpecUB = uibutton(app.GridLayout, 'push');
            app.RegSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecUBPushed, true);
            app.RegSpecUB.Text = 'Update Regression Specifications';
            app.ParameterSpecificationsPanel = uipanel(app.GridLayout);
            app.ParameterSpecificationsPanel.Title = 'Parameter Specifications';
            app.GridLayout2 = uigridlayout(app.ParameterSpecificationsPanel);
            app.RegressionParameterDropDownLabel = uilabel(app.GridLayout2);
            app.RegressionParameterDropDownLabel.Text = 'Regression Parameter';
            app.RegParamDD = uidropdown(app.GridLayout2);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.ValueChangedFcn = createCallbackFcn(app, @RegParamDDValueChanged, true);
            app.RegParamDD.Value = {};
            app.ParameterInitialGuessDropDownLabel = uilabel(app.GridLayout2);
            app.ParameterInitialGuessDropDownLabel.Text = 'Parameter Initial Guess';
            app.ParamIGDD1 = uidropdown(app.GridLayout2);
            app.ParamIGDD1.Items = {};
            app.ParamIGDD1.Editable = 'on';
            app.ParamIGDD1.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD1.Value = {};
            app.toDropDownLabel = uilabel(app.GridLayout2);
            app.toDropDownLabel.Enable = 'off';
            app.toDropDownLabel.Visible = 'off';
            app.toDropDownLabel.Text = 'to';
            app.ParamIGDD2 = uidropdown(app.GridLayout2);
            app.ParamIGDD2.Items = {};
            app.ParamIGDD2.Editable = 'on';
            app.ParamIGDD2.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD2.Enable = 'off';
            app.ParamIGDD2.Visible = 'off';
            app.ParamIGDD2.Value = {};
            app.withDropDownLabel = uilabel(app.GridLayout2);
            app.withDropDownLabel.Enable = 'off';
            app.withDropDownLabel.Visible = 'off';
            app.withDropDownLabel.Text = 'with';
            app.ParamIGDD3 = uidropdown(app.GridLayout2);
            app.ParamIGDD3.Items = {};
            app.ParamIGDD3.Editable = 'on';
            app.ParamIGDD3.ValueChangedFcn = createCallbackFcn(app, @AddNewValToDD, true);
            app.ParamIGDD3.Enable = 'off';
            app.ParamIGDD3.Visible = 'off';
            app.ParamIGDD3.Value = {};
            app.ParamIGLL3 = uilabel(app.GridLayout2);
            app.ParamIGLL3.Enable = 'off';
            app.ParamIGLL3.Visible = 'off';
            app.ParamIGLL3.Text = 'points in range';
            app.DerivStepL = uilabel(app.GridLayout2);
            app.DerivStepL.Text = '';
            app.RelativeDifferenceforFiniteDifferenceGradientDropDownLabel = uilabel(app.GridLayout2);
            app.RelativeDifferenceforFiniteDifferenceGradientDropDownLabel.Text = 'Relative Difference for Finite Difference Gradient';
            app.DerivStepDD = uidropdown(app.GridLayout2);
            app.DerivStepDD.Items = {'Default'};
            app.DerivStepDD.Editable = 'on';
            app.DerivStepDD.ValueChangedFcn = createCallbackFcn(app, @DerivStepDDValueChanged, true);
            app.DerivStepDD.Value = 'Default';
            app.RegParamSpecUB = uibutton(app.GridLayout2, 'push');
            app.RegParamSpecUB.ButtonPushedFcn = createCallbackFcn(app, @RegParamSpecUBButtonPushed, true);
            app.RegParamSpecUB.Text = 'Update';
            app.RegSpecsP = uipanel(app.GridLayout);
            app.RegSpecsP.Title = 'Regression Specifications';
            app.GridLayout3 = uigridlayout(app.RegSpecsP);
            app.TerminationToleranceonResidualSumofSquaresEditFieldLabel = uilabel(app.GridLayout3);
            app.TerminationToleranceonResidualSumofSquaresEditFieldLabel.Text = 'Termination Tolerance on Residual Sum of Squares';
            app.TolFunF = uieditfield(app.GridLayout3, 'numeric');
            app.TolFunF.Value = 1e-08;
            app.TerminationToleranceonEstimatedCoefficientsEditFieldLabel = uilabel(app.GridLayout3);
            app.TerminationToleranceonEstimatedCoefficientsEditFieldLabel.Text = 'Termination Tolerance on Estimated Coefficients';
            app.TolXF = uieditfield(app.GridLayout3, 'numeric');
            app.TolXF.Value = 1e-08;
            app.WeightFunctionDropDownLabel = uilabel(app.GridLayout3);
            app.WeightFunctionDropDownLabel.Text = 'Weight Function';
            app.WFDD = uidropdown(app.GridLayout3);
            app.WFDD.Items = {'None', 'Andrews', 'Bisquare', 'Cauchy', 'Fair', 'Huber', 'Logicstic', 'Talwar', 'Welsch'};
            app.WFDD.Editable = 'on';
            app.WFDD.ValueChangedFcn = createCallbackFcn(app, @WFDDValueChanged, true);
            app.WFDD.Value = 'None';
            app.WtFuncL = uilabel(app.GridLayout3);
            app.WtFuncL.Text = 'N/A';
            app.TuneConstL = uilabel(app.GridLayout3);
            app.TuneConstL.Text = 'N/A';
            app.TuningConstantDropDownLabel = uilabel(app.GridLayout3);
            app.TuningConstantDropDownLabel.Text = 'Tuning Constant';
            app.TuningConstDD = uidropdown(app.GridLayout3);
            app.TuningConstDD.Items = {'Default'};
            app.TuningConstDD.Editable = 'on';
            app.TuningConstDD.ValueChangedFcn = createCallbackFcn(app, @WFDDValueChanged, true);
            app.TuningConstDD.Value = 'Default';
            app.MaximumIterationsSpinnerLabel = uilabel(app.GridLayout3);
            app.MaximumIterationsSpinnerLabel.Text = 'Maximum Iterations';
            app.MaxIterSpin = uispinner(app.GridLayout3);
            app.MaxIterSpin.Value = 100;
            app.FunValCheckCB = uicheckbox(app.GridLayout3);
            app.FunValCheckCB.Text = 'Check for NaN and Inf Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RegSpecsWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end